classdef APPpolimiEEGCodesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Step1CheckMarkersPanel         matlab.ui.container.Panel
        LoadCSVfileButton              matlab.ui.control.Button
        CSVfilenameLabel               matlab.ui.control.Label
        CSVfilefolderLabel             matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencyEditField     matlab.ui.control.NumericEditField
        RuneeglabButton                matlab.ui.control.Button
        tobeloadedLabel                matlab.ui.control.Label
        LoadCSVLamp                    matlab.ui.control.Lamp
        MarkerColumninCSVEditFieldLabel  matlab.ui.control.Label
        MarkerColumninCSVEditField     matlab.ui.control.NumericEditField
        Step2ProcessSignalPanel        matlab.ui.container.Panel
        StartProcessingButton          matlab.ui.control.Button
        TestIDEditFieldLabel           matlab.ui.control.Label
        TestIDEditField                matlab.ui.control.EditField
        MarkerCheckedCheckBox          matlab.ui.control.CheckBox
        CrushedatTaskPanel_2           matlab.ui.container.Panel
        DotsCheckBox_2                 matlab.ui.control.CheckBox
        HairdryerCheckBox_2            matlab.ui.control.CheckBox
        DoorCheckBox_2                 matlab.ui.control.CheckBox
        DoorAfterQCheckBox_2           matlab.ui.control.CheckBox
        ProcessDataLampLabel           matlab.ui.control.Label
        ProcessDataLamp                matlab.ui.control.Lamp
        Step3MeanPowerPanel            matlab.ui.container.Panel
        CopydatafromtableButton        matlab.ui.control.Button
        UITable2                       matlab.ui.control.Table
        PowerMapButton                 matlab.ui.control.Button
        LogScale                       matlab.ui.control.Switch
        ReferenceDropDownLabel         matlab.ui.control.Label
        ReferenceDropDown              matlab.ui.control.DropDown
        PowerMaprejectedButton         matlab.ui.control.Button
        Step4PlotDynamicMeanPanel      matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        AF3CheckBox                    matlab.ui.control.CheckBox
        AF4CheckBox                    matlab.ui.control.CheckBox
        F3CheckBox                     matlab.ui.control.CheckBox
        F4CheckBox                     matlab.ui.control.CheckBox
        F7CheckBox                     matlab.ui.control.CheckBox
        FC5CheckBox                    matlab.ui.control.CheckBox
        F8CheckBox                     matlab.ui.control.CheckBox
        FC6CheckBox                    matlab.ui.control.CheckBox
        T7CheckBox                     matlab.ui.control.CheckBox
        T8CheckBox                     matlab.ui.control.CheckBox
        O1CheckBox                     matlab.ui.control.CheckBox
        O2CheckBox                     matlab.ui.control.CheckBox
        P7CheckBox                     matlab.ui.control.CheckBox
        P8CheckBox                     matlab.ui.control.CheckBox
        Net1CheckBox                   matlab.ui.control.CheckBox
        Net2CheckBox                   matlab.ui.control.CheckBox
        Net3CheckBox                   matlab.ui.control.CheckBox
        TaskDropDownLabel              matlab.ui.control.Label
        TaskDropDown                   matlab.ui.control.DropDown
        TestIDEditField_2Label         matlab.ui.control.Label
        TestIDEditField_2              matlab.ui.control.EditField
        PlotDynamicMeanButton          matlab.ui.control.Button
        CrushLabel                     matlab.ui.control.Label
        CrushSwitch                    matlab.ui.control.Switch
        CrushedatTaskPanel             matlab.ui.container.Panel
        DotsCheckBox                   matlab.ui.control.CheckBox
        HairdryerCheckBox              matlab.ui.control.CheckBox
        DoorCheckBox                   matlab.ui.control.CheckBox
        DoorAfterQCheckBox             matlab.ui.control.CheckBox
        tobeUpdatedLampLabel           matlab.ui.control.Label
        tobeUpdatedLamp                matlab.ui.control.Lamp
        DMWindowsEditFieldLabel        matlab.ui.control.Label
        DMWindowsEditField             matlab.ui.control.NumericEditField
        RejectThresholdEditFieldLabel  matlab.ui.control.Label
        RejectThresholdEditField       matlab.ui.control.NumericEditField
        RejectCheckBox                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        EEGdataset % Description
        EEGmeanrejectedTH % Description
        EEGmean % Description
    end
    
    methods (Access = private)
        function CrushSwitchVisibility(app)
            CrushTaskValue = [app.DotsCheckBox.Value*1 app.HairdryerCheckBox.Value*2  app.DoorCheckBox.Value*3 app.DoorAfterQCheckBox.Value*4];
            %             value1 = app.DotsCheckBox.Value * 1;
            %             value2 = app.HairdryerCheckBox.Value *2;
            %             value3 = app.DoorCheckBox.Value *3;
            %             value4 = app.DoorAfterQCheckBox.Value *4;
            if max(CrushTaskValue)>0 && ~isempty(find(str2double(app.TaskDropDown.Value)==CrushTaskValue, 1))
                app.CrushSwitch.Visible=true;
                app.CrushLabel.Visible=true;
            else
                app.CrushSwitch.Visible=false;
                app.CrushLabel.Visible=false;
            end
        end
        
        function CheckBoxValueSetting(app,ChanV)
            app.AF3CheckBox.Value=ChanV(1); app.AF4CheckBox.Value=ChanV(14);
            app.F7CheckBox.Value=ChanV(2);  app.F8CheckBox.Value=ChanV(13);
            app.F3CheckBox.Value=ChanV(3);  app.F4CheckBox.Value=ChanV(12);
            app.FC5CheckBox.Value=ChanV(4); app.FC6CheckBox.Value=ChanV(11);
            app.T7CheckBox.Value=ChanV(5);  app.T8CheckBox.Value=ChanV(10);
            app.P7CheckBox.Value=ChanV(6);  app.P8CheckBox.Value=ChanV(9);
            app.O1CheckBox.Value=ChanV(7);  app.O2CheckBox.Value=ChanV(8);
            %           DataInAxes(hObject,handles);
        end
        
        function PlotPowerMap(app,xpower,titlereject)
            LogLinear = app.LogScale.Value;
            if sum(xpower(1,:))==1
                warndlg('load mean power from Step 2','No data to plot');
                return
            end
            refxpower = xpower(:,app.ReferenceDropDown.Value);
            
            xpower_ref = xpower(:,2:end)./refxpower;
            
            RLowerLimit = 0;
            RUpperLimit = 200/(100^(ceil(log10(app.ReferenceDropDown.Value)))); titleadd='';
            
            if strcmp(LogLinear,'Log')
                titleadd='\_Log';
                xpower_ref = log(xpower_ref);
                RUpperLimit = 8/(4^(ceil(log10(app.ReferenceDropDown.Value))));
                RLowerLimit = -2;
            end
            %             strjoin([{testi}, {' - '}, app.TaskDropDown.Items(str2double(app.TaskDropDown.Value))])
            titletext=strjoin([{'Pilot Test '}, app.TestIDEditField.Value, {' Mean Power'}, {titleadd}, {'Reference - '}, app.ReferenceDropDown.Items(app.ReferenceDropDown.Value),{titlereject}]);
            theta = deg2rad([107.5:25:432.5 467.5]);
            figure('Units', 'Normalized', 'OuterPosition',[0, 0, 1, 1],'Color',[1 1 1]);%'Renderer', 'painters', 'Position', [0 0 1600 820])
            polarplot(theta,[xpower_ref; xpower_ref(1,:)],'-*','LineWidth',2.5)
            ax=gca;
            ax.RLim=[RLowerLimit,RUpperLimit];
            ax.LineWidth = 0.5;
            ax.ThetaTick =([22.5 47.5 72.5 107.5:25:360]);
            ax.ThetaTickLabel=({'F8','F4','AF4','AF3','F3','F7','FC5','T7','P7','O1','O2','P8','T8','FC6'});
            legend(app.UITable2.ColumnName');
            title(titletext,'FontSize',15);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadCSVfileButton
        function LoadCSVfileButtonPushed(app, event)
            %           path='E:/Politecnico di Milano\Sonia Liliana Da Silva Vieira - Shumin Li - DataShare/Raw_CSV/';
            app.tobeloadedLabel.Text='Loading';
            app.LoadCSVLamp.Color = [1 0 0];
            title='Select CSV file to read Markers';
            [file,path] = uigetfile('*.csv',title);
            %             file='pilot_test_015.csv';
            %             path='E:/try/';
            if length(file)+length(path)>2 %found the file
                app.CSVfilenameLabel.Text = file;
                app.CSVfilefolderLabel .Text = path;
                getIDindex = regexp(file,'\d\d\d');
                app.TestIDEditField.Value = file(getIDindex:getIDindex+2);
                app.TestIDEditField_2.Value = file(getIDindex:getIDindex+2);
                % [srate,Markers,eegdata]
                rawdata = csvread([path file],1,0);
                MarkerCSV = find(rawdata(:,20)==1);
                if isempty(MarkerCSV)
                    warndlg(['There is no Marker in this CSV file at Column ' num2str(app.MarkerColumninCSVEditField.Value) ', please recheck the csv file'],'Check Marker');
                    return
                elseif length(MarkerCSV)==1
                    sraten = (length(rawdata)-MarkerCSV)/(rawdata(length(rawdata),22)-rawdata(MarkerCSV,22));
                else
                    srate=zeros(floor(length(MarkerCSV)/2),1);
                    for markerN=1:length(MarkerCSV)/2
                        srate(markerN) = (MarkerCSV(markerN*2)-MarkerCSV(markerN*2-1))/(rawdata(MarkerCSV(markerN*2),22)-rawdata(MarkerCSV(markerN*2-1),22));
                    end
                    sraten=mean(srate);
                end
                app.SamplingFrequencyEditField.Value = round(mean(sraten)*10)/10;
                MarkerSegment = [1 MarkerCSV' length(rawdata)];
                app.EEGdataset.Data = rawdata(:,3:16)';% 14*n
                % PreCheck if a marker is the right one to be use
                CheckMarker = ones(size(MarkerSegment));
                ColumnLabel=cell(1,length(MarkerSegment));
                for labeln = 2:length(MarkerSegment)-1
                    ColumnLabel{1,labeln} = ['Marker' num2str(labeln-1)];
                    if MarkerSegment(labeln+1)-MarkerSegment(labeln)<127*2
                        CheckMarker(labeln)=0;
                    end
                end
                ColumnLabel{1,1} = 'Data Start';
                ColumnLabel{1,length(MarkerSegment)} = 'Data Length';
                CheckMarker([1 end])=0;
                MarkerSegmentSecond=rawdata(MarkerSegment,22)-rawdata(1,22);%round(MarkerSegment/127);
                app.UITable.ColumnName=ColumnLabel;
                app.UITable.ColumnEditable = true;
                app.UITable.RowName={'Row Number';'Time(s)';'True Marker (1/0)'};
                app.UITable.Data = [MarkerSegment; MarkerSegmentSecond'; CheckMarker];
                app.tobeloadedLabel.Text='Done';
                app.LoadCSVLamp.Color = [0 1 0];
                app.ProcessDataLampLabel.Text='Pending';
                app.ProcessDataLamp.Color = [0.93,0.69,0.13];
                app.UITable2.Data = [];
                app.tobeUpdatedLampLabel.Text = 'to be Updated';
                app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
            else
                app.tobeloadedLabel.Text='to be load';
                app.LoadCSVLamp.Color = [0.93,0.69,0.13];
            end
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            app.ProcessDataLampLabel.Text='Processing';
            app.ProcessDataLamp.Color = [1 0 0];
            pause(0.5);
            
            testi=app.TestIDEditField.Value;
            value1 = app.DotsCheckBox_2.Value; value2 = app.HairdryerCheckBox_2.Value;
            value3 = app.DoorCheckBox_2.Value; value4 = app.DoorAfterQCheckBox_2.Value;
            totalpartN = 4+value1+value2+value3+value4;
            bssccaMean = zeros(14,totalpartN);
            bssccaMeanLog = zeros(14,totalpartN);
            RunProcess = 1; ProcessedDataN=0;
            MeanPowerdataFile = ['ProcessedData/MeanPower/Polimi/bssccaMeanPT' testi '.mat'];
            if exist(MeanPowerdataFile,'file')==2
                AlreadyProcessedData = load(MeanPowerdataFile);
                bssccaMean1=AlreadyProcessedData.bssccaMean;
                ProcessedDataN = find(bssccaMean1(1,:)~=0,1,"last");
                if ProcessedDataN>=4
                    if ProcessedDataN~=totalpartN
                        warndlg('Please check during which task the data stream crushed','Check crushed task');
                        return
                    end
                    RunProcess = 0;
                    bssccaMean=bssccaMean1;
                    lamptext = 'Done';
                    lampcolor = [0 1 0];
                else
                    bssccaMean(:,1:ProcessedDataN)=bssccaMean1(:,1:ProcessedDataN);
                    bssccaMeanLog(:,1:ProcessedDataN)=AlreadyProcessedData.bssccaMeanLog(:,1:ProcessedDataN);
                end
            end
            if RunProcess == 1
                lamptext = 'Pending';
                lampcolor = [0.93,0.69,0.13];
                if length(app.UITable.Data)<3
                    warndlg('Import CSV to check Markers First','Import CSV');
                elseif app.MarkerCheckedCheckBox.Value==false
                    warndlg('Check Markers First','Check Markers');
                    
                else
                    CutTime=app.UITable.Data(1,app.UITable.Data(3,:)==1);
                    if mod(length(CutTime),2)==1
                        warndlg('Wrong Number of Markers, Please Recheck!','Check Marker Number');
                    else
                        RawDatasetpath='RawData/Polimi_set/';
                        FilteredEEGpath = 'ProcessedData/FilteredDataSet/Polimi/';
                        BSSCCAdatasetpath = 'ProcessedData/BSSCCADataSet/Polimi/';
                        for j=1:length(CutTime)/2
                            partk = num2str(j+ProcessedDataN);
                            BSSCCAdataFile = ['ProcessedData/BSSCCADataMat/Polimi/bssccaPT' testi '_p' partk '.mat'];
                            srate=app.SamplingFrequencyEditField.Value;
                            EEG = pop_importdata('dataformat','array','nbchan',0,'data',app.EEGdataset.Data(:,CutTime(2*j-1):CutTime(2*j)),'setname',['PilotTest' testi],'srate',srate,'pnts',0,'xmin',0,'chanlocs','emotiv14loc.ced');
                            EEG.setname=['PilotTest' testi '_p' partk];
                            EEG = pop_saveset( EEG, 'filename',EEG.setname,'filepath',RawDatasetpath);
                            if srate < 130
                                resamplefs = 127; % or 128 as you like
                            else
                                resamplefs = 254; % or 256 as you like
                            end
%                             EEGresample = pop_resample( EEG, resamplefs);
                            %% DC removal
                            EEGdcrm=EEG;%resample;
                            eegdata=EEG.data;%resample.data;
                            IIR_TC = resamplefs*2; %2 second time constant- adjust as required
                            [rows, cols]=size(eegdata);
                            if cols == 14
                                eegdata=eegdata';
                                [rows, cols]=size(eegdata);
                            end
                            EEGdcrm.data = zeros(rows,cols);
                            back= eegdata(:,1);
                            for ii = 2 : cols
                                back = (back*(IIR_TC-1) + eegdata(:,ii)) / IIR_TC;
                                EEGdcrm.data(:,ii)=eegdata(:,ii)- back;
                            end
                            %% 4-45 Filter
                            EEGfiltered = pop_eegfiltnew(EEGdcrm, 'locutoff',4,'hicutoff',45,'plotfreqz',0);
                            EEGfiltered.setname=[EEG.setname 'f']; % PilotTest000_p0f
                            pop_saveset(EEGfiltered,'filename',[EEGfiltered.setname '.set'],'filepath', FilteredEEGpath);
                            %% BSSCCA
                            EEGbsscca=EEGfiltered;
                            EEGbsscca = pop_autobssemg(EEGbsscca, 1, 0.3, 'bsscca', {'eigratio', 1000000}, 'emg_psd', {'ratio', 10,'fs', 127,'femg', 15,'estimator',spectrum.welch,'range', [0  4]});%#ok
                            EEGbsscca = pop_autobsseog(EEGbsscca, 2.5, 1.2, 'bsscca', {'eigratio', 1000000}, 'eog_fd', {'range',[1  4]});
                            EEGbsscca.setname=[EEG.setname 'bsscca'];% PilotTest000_p0bsscca
                            pop_saveset(EEGbsscca,'filename',[EEGbsscca.setname '.set'],'filepath', BSSCCAdatasetpath);
                            %% Save BssccaData.met For dynamic mean
                            bssccaID = EEGbsscca.data;
                            save(BSSCCAdataFile,'bssccaID');
                            %% Step 9 Calculate mean value of bssccaed data, save it in dataset file
                            bssccaMean(:,j+ProcessedDataN) = mean(bssccaID.^2,2);
                            bssccaMeanLog(:,j+ProcessedDataN) = log(bssccaMean(:,j+ProcessedDataN));
                        end
                        save(MeanPowerdataFile,'bssccaMean','bssccaMeanLog');
                        lamptext = 'Done';
                        lampcolor = [0 1 0];
                    end
                end
            end
            EventName={'Dots (Before Crush)';'Dots';'Hairdryer(Before Crush)';'Hairdryer';
                'Door (Before Crush)';'Door';'Door (after Q (Before Crush))';'Door (after Q)'};
            app.UITable2.ColumnName=EventName([value1 1 value2 1 value3 1 value4 1]==1,1);
            app.UITable2.RowName={'AF3';'F3';'F7';'FC5';'T7';'P7';'O1';'O2';'P8';'T8';'FC6';'F8';'F4';'AF4'};
            
            bssccaMean([2 3],:)=bssccaMean([3 2],:);
            bssccaMean([12 13],:)=bssccaMean([13 12],:);
            
            app.UITable2.Data = bssccaMean;
            app.ProcessDataLampLabel.Text=lamptext;
            app.ProcessDataLamp.Color = lampcolor;
            app.EEGmean=app.UITable2.Data;
            
            app.ReferenceDropDown.Items = [{'None'};app.UITable2.ColumnName];
            app.ReferenceDropDown.ItemsData = 1:(length(app.UITable2.Data(1,:))+1);
            app.ReferenceDropDown.Value = 1;
        end

        % Button pushed function: CopydatafromtableButton
        function CopydatafromtableButtonPushed(app, event)
            %             clipboard("copy",app.UITable2.Data);
            A = app.UITable2.Data;  % Write this to file.
            fid = fopen('MeanPower.txt','wt');
            for ii = 1:size(A,1)
                fprintf(fid,'%g\t',A(ii,:));
                fprintf(fid,'\n');
            end
            fclose(fid);
            winopen('MeanPower.txt')
        end

        % Button pushed function: RuneeglabButton
        function RuneeglabButtonPushed(app, event)
            eeglab;
        end

        % Value changed function: DotsCheckBox
        function DotsCheckBoxValueChanged(app, event)
            CrushSwitchVisibility(app);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: HairdryerCheckBox
        function HairdryerCheckBoxValueChanged(app, event)
            CrushSwitchVisibility(app);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: DoorCheckBox
        function DoorCheckBoxValueChanged(app, event)
            CrushSwitchVisibility(app);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: DoorAfterQCheckBox
        function DoorAfterQCheckBoxValueChanged(app, event)
            CrushSwitchVisibility(app);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Button pushed function: PowerMapButton
        function PowerMapButtonPushed(app, event)
            xpower = [ones(14,1), app.EEGmean];
            app.Step3MeanPowerPanel.Title = 'Step 3 -- Mean Power';
            app.UITable2.Data = app.EEGmean;
            titlereject = '';
            PlotPowerMap(app,xpower,titlereject);
        end

        % Value changed function: TestIDEditField
        function TestIDEditFieldValueChanged(app, event)
            app.TestIDEditField_2.Value = app.TestIDEditField.Value;
            
        end

        % Value changed function: Net1CheckBox
        function Net1CheckBoxValueChanged(app, event)
            CheckedValue = [0 0 0 1 0 0 0 1 1 0 1 0 0 0] * app.Net1CheckBox.Value...
                + [1 0 0 0 0 0 0 0 0 0 0 1 1 1] * app.Net2CheckBox.Value...
                + [0 1 1 0 1 1 1 0 0 1 0 0 0 0] * app.Net3CheckBox.Value;
            CheckBoxValueSetting(app,CheckedValue);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: Net2CheckBox
        function Net2CheckBoxValueChanged(app, event)
            CheckedValue = [0 0 0 1 0 0 0 1 1 0 1 0 0 0] * app.Net1CheckBox.Value...
                + [1 0 0 0 0 0 0 0 0 0 0 1 1 1] * app.Net2CheckBox.Value...
                + [0 1 1 0 1 1 1 0 0 1 0 0 0 0] * app.Net3CheckBox.Value;
            CheckBoxValueSetting(app,CheckedValue);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: Net3CheckBox
        function Net3CheckBoxValueChanged(app, event)
            CheckedValue = [0 0 0 1 0 0 0 1 1 0 1 0 0 0] * app.Net1CheckBox.Value...
                + [1 0 0 0 0 0 0 0 0 0 0 1 1 1] * app.Net2CheckBox.Value...
                + [0 1 1 0 1 1 1 0 0 1 0 0 0 0] * app.Net3CheckBox.Value;
            CheckBoxValueSetting(app,CheckedValue);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Button pushed function: PlotDynamicMeanButton
        function PlotDynamicMeanButtonPushed(app, event)
            testi = app.TestIDEditField_2.Value;
            partk = str2double(app.TaskDropDown.Value);
            CheckEvent = [app.DotsCheckBox.Value, app.HairdryerCheckBox.Value, app.DoorCheckBox.Value, app.DoorAfterQCheckBox.Value];
            for i = 1: partk
                partk = partk+ CheckEvent(i);
            end
            if strcmp(app.CrushSwitch.Visible,'on')
                beforeCrush = str2double(app.CrushSwitch.Value)-1; %before = -1, after = 0   1 2   3 4  5  6
                partk = partk + beforeCrush;
            end
            filepath=['ProcessedData/BSSCCADataMat/Polimi/bssccaPT' testi '_p'];
            if exist([filepath '1.mat'],'file') ~= 2
                warndlg('No Such Subject.','Check Test ID');
            elseif exist([filepath num2str(partk) '.mat'],'file') ~= 2
                warndlg('Please check during which task the data stream crushed','Check crushed task');
            else
                datastream=load([filepath num2str(partk) '.mat']);
                xpowerdata = datastream.bssccaID.^2; 
                range = 127*app.DMWindowsEditField.Value;
                movemeanxpower = movmean(xpowerdata,range,2);
                if app.RejectCheckBox.Value==1
                    RejectTH=app.RejectThresholdEditField.Value;
                    for times=1:length(movemeanxpower)
                        if max(movemeanxpower(:,times))>RejectTH
                            if times+range < length(movemeanxpower)
                                xpowerdata(:,times:(times+range))=nan;
                            else
                                xpowerdata(:,times:end)=nan;
                            end
                        end
                    end
                end
                movemean = movmean(xpowerdata,range,2);%log()
                timex=(1:length(movemean))/127/60;%+addtime(IDi);
                CtP = [app.AF3CheckBox.Value,app.F7CheckBox.Value,app.F3CheckBox.Value,...
                    app.FC5CheckBox.Value,app.T7CheckBox.Value,app.P7CheckBox.Value,app.O1CheckBox.Value,...
                    app.O2CheckBox.Value,app.P8CheckBox.Value,app.T8CheckBox.Value,app.FC6CheckBox.Value,...
                    app.F4CheckBox.Value,app.F8CheckBox.Value,app.AF4CheckBox.Value];
                ChanList={'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
                if sum(CtP)==0
                    app.AF3CheckBox.Value = 1;
                    CtP(1)=1;
                end
                plot(app.UIAxes,timex,movemean(CtP==1,:));
                app.UIAxes.XLim = [0 ceil(timex(end))];
                app.UIAxes.YLim = [0 500];
                app.UIAxes.XGrid = true;
                app.UIAxes.YGrid = true;
                legend(app.UIAxes,ChanList{1,CtP==1});
                title(app.UIAxes,strjoin([{testi}, {' - '}, app.TaskDropDown.Items(str2double(app.TaskDropDown.Value))]));
                app.tobeUpdatedLampLabel.Text = 'Updated';
                app.tobeUpdatedLamp.Color = [0 1 0];
            end
        end

        % Value changed function: DMWindowsEditField
        function DMWindowsEditFieldValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: TestIDEditField_2
        function TestIDEditField_2ValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
            CrushSwitchVisibility(app);
        end

        % Value changed function: AF3CheckBox
        function AF3CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: AF4CheckBox
        function AF4CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: F3CheckBox
        function F3CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: F4CheckBox
        function F4CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: F7CheckBox
        function F7CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: F8CheckBox
        function F8CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: FC5CheckBox
        function FC5CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: FC6CheckBox
        function FC6CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: T7CheckBox
        function T7CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: T8CheckBox
        function T8CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: P7CheckBox
        function P7CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: P8CheckBox
        function P8CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: O1CheckBox
        function O1CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: O2CheckBox
        function O2CheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: CrushSwitch
        function CrushSwitchValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: RejectThresholdEditField
        function RejectThresholdEditFieldValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Value changed function: RejectCheckBox
        function RejectCheckBoxValueChanged(app, event)
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp.Color = [0.93,0.69,0.13];
        end

        % Button pushed function: PowerMaprejectedButton
        function PowerMaprejectedButtonPushed(app, event)
            if isempty(app.UITable2.Data)
                warndlg('Before reject high power epoch, load Mean Power by Step 2 first')
                return
            end
            RejectTH=app.RejectThresholdEditField.Value;
            range = app.DMWindowsEditField.Value*127;
            testi = app.TestIDEditField.Value;
            app.EEGmeanrejectedTH = zeros(size(app.UITable2.Data));
            for partk = 1:length(app.UITable2.Data(1,:))
                filepath = ['ProcessedData/BSSCCADataMat/Polimi/bssccaPT' testi '_p' num2str(partk) '.mat'];
                datastream = load(filepath);
                xpowerdata = datastream.bssccaID.^2;
                movemeanxpower = movmean(xpowerdata,range,2);
                for times=1:length(movemeanxpower)
                    if max(movemeanxpower(:,times))>RejectTH
                        if times+range < length(movemeanxpower)
                            xpowerdata(:,times:(times+range))=nan;
                        else
                            xpowerdata(:,times:end)=nan;
                        end
                    end
                end
                app.EEGmeanrejectedTH(:,partk)=mean(xpowerdata(:,~isnan(xpowerdata(1,:))),2);
            end
            app.EEGmeanrejectedTH([2 3],:)=app.EEGmeanrejectedTH([3 2],:);
            app.EEGmeanrejectedTH([12 13],:)=app.EEGmeanrejectedTH([13 12],:);
            xpower=[ones(14,1), app.EEGmeanrejectedTH];
            app.Step3MeanPowerPanel.Title = 'Step 3 -- Mean Power (rejected)';
            app.UITable2.Data = app.EEGmeanrejectedTH;
            titlereject = ['(rejected higher than ' num2str(app.RejectThresholdEditField.Value) ' \muv^2)'];
            PlotPowerMap(app,xpower,titlereject);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            MarkersN=find(app.UITable.Data(3,:)==1);
            if length(MarkersN)>=2
                for nn=1:length(MarkersN)/2
                    srate(nn)=(app.UITable.Data(1,MarkersN(nn*2))-app.UITable.Data(1,MarkersN(nn*2-1))+1)/(app.UITable.Data(2,MarkersN(nn*2))-app.UITable.Data(2,MarkersN(nn*2-1))+1);%#ok
                end
                app.SamplingFrequencyEditField.Value = mean(srate);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Step1CheckMarkersPanel = uipanel(app.UIFigure);
            app.Step1CheckMarkersPanel.Title = 'Step 1 -- Check Markers';
            app.LoadCSVfileButton = uibutton(app.Step1CheckMarkersPanel, 'push');
            app.LoadCSVfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCSVfileButtonPushed, true);
            app.LoadCSVfileButton.Text = 'Load CSV file';
            app.CSVfilenameLabel = uilabel(app.Step1CheckMarkersPanel);
            app.CSVfilenameLabel.Text = 'CSV file name';
            app.CSVfilefolderLabel = uilabel(app.Step1CheckMarkersPanel);
            app.CSVfilefolderLabel.Text = 'CSV file folder';
            app.UITable = uitable(app.Step1CheckMarkersPanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SamplingFrequencyEditFieldLabel = uilabel(app.Step1CheckMarkersPanel);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.Step1CheckMarkersPanel, 'numeric');
            app.SamplingFrequencyEditField.Value = 127;
            app.RuneeglabButton = uibutton(app.Step1CheckMarkersPanel, 'push');
            app.RuneeglabButton.ButtonPushedFcn = createCallbackFcn(app, @RuneeglabButtonPushed, true);
            app.RuneeglabButton.Text = 'Run eeglab';
            app.tobeloadedLabel = uilabel(app.Step1CheckMarkersPanel);
            app.tobeloadedLabel.Text = 'to be loaded';
            app.LoadCSVLamp = uilamp(app.Step1CheckMarkersPanel);
            app.MarkerColumninCSVEditFieldLabel = uilabel(app.Step1CheckMarkersPanel);
            app.MarkerColumninCSVEditFieldLabel.Text = 'Marker Column in CSV';
            app.MarkerColumninCSVEditField = uieditfield(app.Step1CheckMarkersPanel, 'numeric');
            app.MarkerColumninCSVEditField.Value = 20;
            app.Step2ProcessSignalPanel = uipanel(app.UIFigure);
            app.Step2ProcessSignalPanel.Title = 'Step 2 -- Process Signal';
            app.StartProcessingButton = uibutton(app.Step2ProcessSignalPanel, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.TestIDEditFieldLabel = uilabel(app.Step2ProcessSignalPanel);
            app.TestIDEditFieldLabel.Text = 'Test ID';
            app.TestIDEditField = uieditfield(app.Step2ProcessSignalPanel, 'text');
            app.TestIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestIDEditFieldValueChanged, true);
            app.TestIDEditField.Value = '000';
            app.MarkerCheckedCheckBox = uicheckbox(app.Step2ProcessSignalPanel);
            app.MarkerCheckedCheckBox.Text = '  Marker Checked';
            app.CrushedatTaskPanel_2 = uipanel(app.Step2ProcessSignalPanel);
            app.CrushedatTaskPanel_2.Title = 'Crushed at Task';
            app.DotsCheckBox_2 = uicheckbox(app.CrushedatTaskPanel_2);
            app.DotsCheckBox_2.Text = 'Dots';
            app.HairdryerCheckBox_2 = uicheckbox(app.CrushedatTaskPanel_2);
            app.HairdryerCheckBox_2.Text = 'Hairdryer';
            app.DoorCheckBox_2 = uicheckbox(app.CrushedatTaskPanel_2);
            app.DoorCheckBox_2.Text = 'Door';
            app.DoorAfterQCheckBox_2 = uicheckbox(app.CrushedatTaskPanel_2);
            app.DoorAfterQCheckBox_2.Text = 'Door (After Q)';
            app.ProcessDataLampLabel = uilabel(app.Step2ProcessSignalPanel);
            app.ProcessDataLampLabel.Text = 'pending';
            app.ProcessDataLamp = uilamp(app.Step2ProcessSignalPanel);
            app.Step3MeanPowerPanel = uipanel(app.UIFigure);
            app.Step3MeanPowerPanel.Title = 'Step 3 -- Mean Power';
            app.CopydatafromtableButton = uibutton(app.Step3MeanPowerPanel, 'push');
            app.CopydatafromtableButton.ButtonPushedFcn = createCallbackFcn(app, @CopydatafromtableButtonPushed, true);
            app.CopydatafromtableButton.Text = 'Copy data from table';
            app.UITable2 = uitable(app.Step3MeanPowerPanel);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {''};
            app.PowerMapButton = uibutton(app.Step3MeanPowerPanel, 'push');
            app.PowerMapButton.ButtonPushedFcn = createCallbackFcn(app, @PowerMapButtonPushed, true);
            app.PowerMapButton.Text = 'PowerMap';
            app.LogScale = uiswitch(app.Step3MeanPowerPanel, 'slider');
            app.LogScale.Items = {'Linear', 'Log'};
            app.LogScale.Value = 'Linear';
            app.ReferenceDropDownLabel = uilabel(app.Step3MeanPowerPanel);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.Step3MeanPowerPanel);
            app.ReferenceDropDown.Items = {'None'};
            app.ReferenceDropDown.Value = 'None';
            app.PowerMaprejectedButton = uibutton(app.Step3MeanPowerPanel, 'push');
            app.PowerMaprejectedButton.ButtonPushedFcn = createCallbackFcn(app, @PowerMaprejectedButtonPushed, true);
            app.PowerMaprejectedButton.Text = {'PowerMap'; ' (rejected)'};
            app.Step4PlotDynamicMeanPanel = uipanel(app.UIFigure);
            app.Step4PlotDynamicMeanPanel.Title = 'Step 4 -- Plot Dynamic Mean';
            app.UIAxes = uiaxes(app.Step4PlotDynamicMeanPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Power (\muV^2)')
            app.AF3CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.AF3CheckBox.ValueChangedFcn = createCallbackFcn(app, @AF3CheckBoxValueChanged, true);
            app.AF3CheckBox.Text = 'AF3';
            app.AF4CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.AF4CheckBox.ValueChangedFcn = createCallbackFcn(app, @AF4CheckBoxValueChanged, true);
            app.AF4CheckBox.Text = 'AF4';
            app.F3CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.F3CheckBox.ValueChangedFcn = createCallbackFcn(app, @F3CheckBoxValueChanged, true);
            app.F3CheckBox.Text = 'F3';
            app.F4CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.F4CheckBox.ValueChangedFcn = createCallbackFcn(app, @F4CheckBoxValueChanged, true);
            app.F4CheckBox.Text = 'F4';
            app.F7CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.F7CheckBox.ValueChangedFcn = createCallbackFcn(app, @F7CheckBoxValueChanged, true);
            app.F7CheckBox.Text = 'F7';
            app.FC5CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.FC5CheckBox.ValueChangedFcn = createCallbackFcn(app, @FC5CheckBoxValueChanged, true);
            app.FC5CheckBox.Text = 'FC5';
            app.F8CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.F8CheckBox.ValueChangedFcn = createCallbackFcn(app, @F8CheckBoxValueChanged, true);
            app.F8CheckBox.Text = 'F8';
            app.FC6CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.FC6CheckBox.ValueChangedFcn = createCallbackFcn(app, @FC6CheckBoxValueChanged, true);
            app.FC6CheckBox.Text = 'FC6';
            app.T7CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.T7CheckBox.ValueChangedFcn = createCallbackFcn(app, @T7CheckBoxValueChanged, true);
            app.T7CheckBox.Text = 'T7';
            app.T8CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.T8CheckBox.ValueChangedFcn = createCallbackFcn(app, @T8CheckBoxValueChanged, true);
            app.T8CheckBox.Text = 'T8';
            app.O1CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.O1CheckBox.ValueChangedFcn = createCallbackFcn(app, @O1CheckBoxValueChanged, true);
            app.O1CheckBox.Text = 'O1';
            app.O2CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.O2CheckBox.ValueChangedFcn = createCallbackFcn(app, @O2CheckBoxValueChanged, true);
            app.O2CheckBox.Text = 'O2';
            app.P7CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.P7CheckBox.ValueChangedFcn = createCallbackFcn(app, @P7CheckBoxValueChanged, true);
            app.P7CheckBox.Text = 'P7';
            app.P8CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.P8CheckBox.ValueChangedFcn = createCallbackFcn(app, @P8CheckBoxValueChanged, true);
            app.P8CheckBox.Text = 'P8';
            app.Net1CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.Net1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net1CheckBoxValueChanged, true);
            app.Net1CheckBox.Text = 'Net 1';
            app.Net2CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.Net2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net2CheckBoxValueChanged, true);
            app.Net2CheckBox.Text = 'Net 2';
            app.Net3CheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.Net3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net3CheckBoxValueChanged, true);
            app.Net3CheckBox.Text = 'Net 3';
            app.TaskDropDownLabel = uilabel(app.Step4PlotDynamicMeanPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.Step4PlotDynamicMeanPanel);
            app.TaskDropDown.Items = {'Dots', 'Hairdryer', 'Door', 'Door (after Q)'};
            app.TaskDropDown.ItemsData = {'1', '2', '3', '4'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = '1';
            app.TestIDEditField_2Label = uilabel(app.Step4PlotDynamicMeanPanel);
            app.TestIDEditField_2Label.Text = 'Test ID';
            app.TestIDEditField_2 = uieditfield(app.Step4PlotDynamicMeanPanel, 'text');
            app.TestIDEditField_2.ValueChangedFcn = createCallbackFcn(app, @TestIDEditField_2ValueChanged, true);
            app.TestIDEditField_2.Value = '000';
            app.PlotDynamicMeanButton = uibutton(app.Step4PlotDynamicMeanPanel, 'push');
            app.PlotDynamicMeanButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDynamicMeanButtonPushed, true);
            app.PlotDynamicMeanButton.Text = 'Plot Dynamic Mean';
            app.CrushLabel = uilabel(app.Step4PlotDynamicMeanPanel);
            app.CrushLabel.Visible = 'off';
            app.CrushLabel.Text = '(Crush)';
            app.CrushSwitch = uiswitch(app.Step4PlotDynamicMeanPanel, 'slider');
            app.CrushSwitch.Items = {'After', 'Before'};
            app.CrushSwitch.ItemsData = {'1', '0'};
            app.CrushSwitch.ValueChangedFcn = createCallbackFcn(app, @CrushSwitchValueChanged, true);
            app.CrushSwitch.Visible = 'off';
            app.CrushSwitch.Value = '0';
            app.CrushedatTaskPanel = uipanel(app.Step4PlotDynamicMeanPanel);
            app.CrushedatTaskPanel.Title = 'Crushed at Task';
            app.DotsCheckBox = uicheckbox(app.CrushedatTaskPanel);
            app.DotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DotsCheckBoxValueChanged, true);
            app.DotsCheckBox.Text = 'Dots';
            app.HairdryerCheckBox = uicheckbox(app.CrushedatTaskPanel);
            app.HairdryerCheckBox.ValueChangedFcn = createCallbackFcn(app, @HairdryerCheckBoxValueChanged, true);
            app.HairdryerCheckBox.Text = 'Hairdryer';
            app.DoorCheckBox = uicheckbox(app.CrushedatTaskPanel);
            app.DoorCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoorCheckBoxValueChanged, true);
            app.DoorCheckBox.Text = 'Door';
            app.DoorAfterQCheckBox = uicheckbox(app.CrushedatTaskPanel);
            app.DoorAfterQCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoorAfterQCheckBoxValueChanged, true);
            app.DoorAfterQCheckBox.Text = 'Door (After Q)';
            app.tobeUpdatedLampLabel = uilabel(app.Step4PlotDynamicMeanPanel);
            app.tobeUpdatedLampLabel.Text = 'to be Updated';
            app.tobeUpdatedLamp = uilamp(app.Step4PlotDynamicMeanPanel);
            app.DMWindowsEditFieldLabel = uilabel(app.Step4PlotDynamicMeanPanel);
            app.DMWindowsEditFieldLabel.Text = 'DM Window (s)';
            app.DMWindowsEditField = uieditfield(app.Step4PlotDynamicMeanPanel, 'numeric');
            app.DMWindowsEditField.ValueChangedFcn = createCallbackFcn(app, @DMWindowsEditFieldValueChanged, true);
            app.DMWindowsEditField.Value = 10;
            app.RejectThresholdEditFieldLabel = uilabel(app.Step4PlotDynamicMeanPanel);
            app.RejectThresholdEditFieldLabel.Text = 'Reject Threshold';
            app.RejectThresholdEditField = uieditfield(app.Step4PlotDynamicMeanPanel, 'numeric');
            app.RejectThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @RejectThresholdEditFieldValueChanged, true);
            app.RejectThresholdEditField.Value = 400;
            app.RejectCheckBox = uicheckbox(app.Step4PlotDynamicMeanPanel);
            app.RejectCheckBox.ValueChangedFcn = createCallbackFcn(app, @RejectCheckBoxValueChanged, true);
            app.RejectCheckBox.Text = 'Reject';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPpolimiEEGCodesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end