classdef IndexDistribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIditri                     matlab.ui.Figure
        signranktestLabel_3         matlab.ui.control.Label
        StatisticalTestsssdsignificantstatisticaldifferencePanel  matlab.ui.container.Panel
        NoPlotsCheckBox             matlab.ui.control.CheckBox
        HrktestLabel                matlab.ui.control.Label
        WilcoxonranksumtestLabel    matlab.ui.control.Label
        HttestLabel                 matlab.ui.control.Label
        ttestLabel                  matlab.ui.control.Label
        HvarLabel                   matlab.ui.control.Label
        EqualVariancetestLabel      matlab.ui.control.Label
        Hnorm1Label                 matlab.ui.control.Label
        NormalDistributionLabel     matlab.ui.control.Label
        Hnorm2Label                 matlab.ui.control.Label
        HsrtestLabel                matlab.ui.control.Label
        signranktestLabel           matlab.ui.control.Label
        ifnotthatbothdatasethavenormaldistributionif1Label  matlab.ui.control.Label
        onlyifbothdatasethavenormaldistributionif1Label  matlab.ui.control.Label
        onlyifbothdatasetsarenormallydistributedif0equalvarianceLabel  matlab.ui.control.Label
        if0normallydistributeLabel  matlab.ui.control.Label
        If1ssdexistbetweenthepairedsetsLabel  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        signranktestLabel_2         matlab.ui.control.Label
        closeallplotsButton         matlab.ui.control.Button
        PlotDistributionButton      matlab.ui.control.Button
        Plot2Panel                  matlab.ui.container.Panel
        ExcludeLACheckBox_2         matlab.ui.control.CheckBox
        ExcludeNECheckBox_2         matlab.ui.control.CheckBox
        ExcludeFCheckBox_2          matlab.ui.control.CheckBox
        AreaDropDown_2              matlab.ui.control.DropDown
        AreaDropDown_2Label         matlab.ui.control.Label
        RefDropDown_2               matlab.ui.control.DropDown
        RefDropDown_2Label          matlab.ui.control.Label
        OptionDropDown_2            matlab.ui.control.DropDown
        OptionDropDown_2Label       matlab.ui.control.Label
        BandDropDown_2              matlab.ui.control.DropDown
        BandDropDown_2Label         matlab.ui.control.Label
        StageDropDown_2             matlab.ui.control.DropDown
        StageDropDown_2Label        matlab.ui.control.Label
        TaskDropDown_2              matlab.ui.control.DropDown
        TaskDropDown_2Label         matlab.ui.control.Label
        Plot1Panel                  matlab.ui.container.Panel
        ExcludeLACheckBox           matlab.ui.control.CheckBox
        ExcludeNECheckBox           matlab.ui.control.CheckBox
        ExcludeFCheckBox            matlab.ui.control.CheckBox
        PlotTRPDistributionButton   matlab.ui.control.Button
        AreaDropDown                matlab.ui.control.DropDown
        AreaDropDownLabel           matlab.ui.control.Label
        SubjectDropDown             matlab.ui.control.DropDown
        SubjectDropDownLabel        matlab.ui.control.Label
        RefDropDown                 matlab.ui.control.DropDown
        RefDropDownLabel            matlab.ui.control.Label
        OptionDropDown              matlab.ui.control.DropDown
        OptionDropDownLabel         matlab.ui.control.Label
        BandDropDown                matlab.ui.control.DropDown
        BandDropDownLabel           matlab.ui.control.Label
        StageDropDown               matlab.ui.control.DropDown
        StageDropDownLabel          matlab.ui.control.Label
        TaskDropDown                matlab.ui.control.DropDown
        TaskDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Indexs % Description
        TRPdata
    end
    
    methods (Access = private)
        
        function IndexDistr(app)
            TaskiL=['Indexs' num2str(app.TaskDropDown.Value)];
            TRPTaskiL=['TRPStage' num2str(app.TaskDropDown.Value)];
            refGL=app.RefDropDown.Items{app.RefDropDown.Value}(1:3);
            TaskiR=['Indexs' num2str(app.TaskDropDown_2.Value)];
            TRPTaskiR=['TRPStage' num2str(app.TaskDropDown_2.Value)];
            refGR=app.RefDropDown_2.Items{app.RefDropDown_2.Value}(1:3);
            
            SubjectN1=[1:7 9:10 12:19 21:28];SubjectN2=[1:7 9:10 12:19 21:28];
            if app.ExcludeFCheckBox.Value==1
                SubjectN1 = setdiff(SubjectN1,[3 16 18 22]);
            end
            if app.ExcludeNECheckBox.Value==1
                SubjectN1 = setdiff(SubjectN1,[1 6]);
            end
            if app.ExcludeLACheckBox.Value==1
                SubjectN1 = setdiff(SubjectN1,[9 10 14 24 27 28]);
            end
            if app.ExcludeFCheckBox_2.Value==1
                SubjectN2 = setdiff(SubjectN2,[3 16 18 22]);
            end
            if app.ExcludeNECheckBox_2.Value==1
                SubjectN2 = setdiff(SubjectN2,[1 6]);
            end
            if app.ExcludeLACheckBox_2.Value==1
                SubjectN2 = setdiff(SubjectN2,[9 10 14 24 27 28]);
            end
            ThisPlotDataL1=app.Indexs.(TaskiL).(refGL)(app.StageDropDown.Value).Option(app.OptionDropDown.Value).SubBands(app.BandDropDown.Value).activity(SubjectN1,app.AreaDropDown.Value);
            ThisPlotDataL2=app.Indexs.(TaskiL).(refGL)(app.StageDropDown.Value).Option(app.OptionDropDown.Value).SubBands(app.BandDropDown.Value).mobility(SubjectN1,app.AreaDropDown.Value);
            ThisPlotDataL3=app.Indexs.(TaskiL).(refGL)(app.StageDropDown.Value).Option(app.OptionDropDown.Value).SubBands(app.BandDropDown.Value).complexity(SubjectN1,app.AreaDropDown.Value);
            ThisPlotDataR1=app.Indexs.(TaskiR).(refGR)(app.StageDropDown_2.Value).Option(app.OptionDropDown_2.Value).SubBands(app.BandDropDown_2.Value).activity(SubjectN2,app.AreaDropDown_2.Value);
            ThisPlotDataR2=app.Indexs.(TaskiR).(refGR)(app.StageDropDown_2.Value).Option(app.OptionDropDown_2.Value).SubBands(app.BandDropDown_2.Value).mobility(SubjectN2,app.AreaDropDown_2.Value);
            ThisPlotDataR3=app.Indexs.(TaskiR).(refGR)(app.StageDropDown_2.Value).Option(app.OptionDropDown_2.Value).SubBands(app.BandDropDown_2.Value).complexity(SubjectN2,app.AreaDropDown_2.Value);
            ThisPlotDataL4=nan(size(SubjectN1));ThisPlotDataR4=nan(size(SubjectN2));
            for subjecti=SubjectN1
                ThisPlotDataL4(subjecti==SubjectN1)=median(app.TRPdata.(TRPTaskiL).(refGL)(app.StageDropDown.Value).SubBands(app.BandDropDown.Value).TRPMovWin(subjecti).Option(app.OptionDropDown.Value).TRPareas(app.AreaDropDown.Value,:));
            end
            for subjecti=SubjectN2
                ThisPlotDataR4(subjecti==SubjectN2)=median(app.TRPdata.(TRPTaskiR).(refGR)(app.StageDropDown_2.Value).SubBands(app.BandDropDown_2.Value).TRPMovWin(subjecti).Option(app.OptionDropDown_2.Value).TRPareas(app.AreaDropDown_2.Value,:));
            end
            if app.NoPlotsCheckBox.Value==0
                TaskStageTitleL=[app.TaskDropDown.Items{app.TaskDropDown.Value} ' Stage-' app.StageDropDown.Items{min(app.StageDropDown.Value,3)} ' ' ...
                    app.AreaDropDown.Items{app.AreaDropDown.Value} ' ' app.BandDropDown.Items{app.BandDropDown.Value}];
                TaskStageTitleR=[app.TaskDropDown.Items{app.TaskDropDown_2.Value} ' Stage-' app.StageDropDown_2.Items{min(app.StageDropDown_2.Value,3)} ' ' ...
                    app.AreaDropDown.Items{app.AreaDropDown_2.Value} ' ' app.BandDropDown.Items{app.BandDropDown_2.Value}];
                if app.OptionDropDown.Value==1
                    histogramcolor='#0072BD';
                else
                    histogramcolor='#D95319';
                end
                if app.OptionDropDown_2.Value==1
                    histogramcolor2='#0072BD';
                else
                    histogramcolor2='#D95319';
                end
                figure("Position",[800 0 1000 1000])
                binedgeActivity=0:0.1:1;
                subplot(4,2,1)
                histogram(ThisPlotDataL1,binedgeActivity,'FaceColor',histogramcolor)
                %histfit(ThisPlotDataL1,6);
                xlim([0 1]);
                ylim([0 30]);
                title([TaskStageTitleL  ' - activity']);
                subplot(4,2,2)
                histogram(ThisPlotDataR1,binedgeActivity,'FaceColor',histogramcolor2)
                %histfit(ThisPlotDataR1,6);
                xlim([0 1]);
                ylim([0 30]);
                title([TaskStageTitleR  ' - activity']);
                
                binedgeMobility=0:0.1:1.2;
                subplot(4,2,3)
                histogram(ThisPlotDataL2,binedgeMobility,'FaceColor',histogramcolor)
                %histfit(ThisPlotDataL2,6);
                xlim([0 1.2]);
                ylim([0 15]);
                title([TaskStageTitleL ' - mobility']);
                subplot(4,2,4)
                histogram(ThisPlotDataR2,binedgeMobility,'FaceColor',histogramcolor2)
                %histfit(ThisPlotDataR2,6);
                xlim([0 1.2]);
                ylim([0 15]);
                title([TaskStageTitleR  ' - mobility']);
                
                binedgeComplexity=0:0.5:5;
                subplot(4,2,5)
                histogram(ThisPlotDataL3,binedgeComplexity,'FaceColor',histogramcolor)
                %histfit(ThisPlotDataL3,6);
                xlim([0 5]);
                ylim([0 25]);
                title([TaskStageTitleL  ' - complexity']);
                subplot(4,2,6)
                histogram(ThisPlotDataR3,binedgeComplexity,'FaceColor',histogramcolor2)
                %histfit(ThisPlotDataR3,6);
                xlim([0 5]);
                ylim([0 25]);
                title([TaskStageTitleR ' - complexity']);
                
                binedgeTRP=-1.5:0.25:1.5;
                subplot(4,2,7)
                histogram(ThisPlotDataL4,binedgeTRP,'FaceColor',histogramcolor)
                %histfit(ThisPlotDataL4,6);
                xlim([-1.5 1.5]);
                ylim([0 15]);
                title([TaskStageTitleL  ' - TRP']);
                subplot(4,2,8)
                histogram(ThisPlotDataR4,binedgeTRP,'FaceColor',histogramcolor2)
                %histfit(ThisPlotDataR4,6);
                xlim([-1.5 1.5]);
                ylim([0 15]);
                title([TaskStageTitleR ' - TRP']);
            end
            StatisticalTest(app,ThisPlotDataL4,ThisPlotDataR4,SubjectN1,SubjectN2)
        end
        function TRPDistr(app)
            Taski=['TRPStage' num2str(app.TaskDropDown.Value)];
            refG=app.RefDropDown.Items{app.RefDropDown.Value}(1:3);
            ThisPlotData=app.TRPdata.(Taski).(refG)(app.StageDropDown.Value).SubBands(app.BandDropDown.Value).TRPMovWin(app.SubjectDropDown.Value).Option(app.OptionDropDown.Value).TRPareas(app.AreaDropDown.Value,:);
            TaskStageTitle=[app.TaskDropDown.Items{app.TaskDropDown.Value} ' Stage-' app.StageDropDown.Items{app.StageDropDown.Value} ' '];
            figure
            histfit(ThisPlotData,6);
            xlim([-1.5 1.5]);
            title([TaskStageTitle app.AreaDropDown.Items{app.AreaDropDown.Value} ' ' app.BandDropDown.Items{app.BandDropDown.Value} ...
                ' Subject' num2str(app.SubjectDropDown.Value) ' - TRP']);
        end
        
        function StatisticalTest(app,ThisPlotDataL4,ThisPlotDataR4,SubjectN1,SubjectN2)
            
            [hl,pl]=kstest(ThisPlotDataL4);app.Hnorm1Label.Text = [num2str(hl) ' p(' num2str(pl) ')'];
            [hr,pr]=kstest(ThisPlotDataR4);app.Hnorm2Label.Text = [num2str(hr) ' p(' num2str(pr) ')'];
            if hl==0&&hr==0
                [h_var,p_var]=vartest2(ThisPlotDataL4,ThisPlotDataR4);
                app.HvarLabel.Text=[num2str(h_var) ' (p=' num2str(p_var) ')'];
                if h_var==0
                    [h_tt2,p_tt2]=ttest2(ThisPlotDataR4,ThisPlotDataR4,'Vartype','equal');
                    app.HttestLabel.Text=[num2str(h_tt2) ' (p=' num2str(p_tt2) ')'];
                else
                    [h_tt2,p_tt2]=ttest2(ThisPlotDataR4,ThisPlotDataR4,'Vartype','unequal');
                    app.HttestLabel.Text=[num2str(h_tt2) ' (p=' num2str(p_tt2) ')'];
                end
                app.HrktestLabel.Text='NaN';
            else
                app.HvarLabel.Text='NaN';
                app.HttestLabel.Text='NaN';
                [p_rk,h_rk] = ranksum(ThisPlotDataL4,ThisPlotDataR4);
                app.HrktestLabel.Text=[num2str(h_rk) ' p(' num2str(p_rk) ')'];
            end
            app.UITable.Data = [];
            if length(SubjectN1)~=length(SubjectN2)
                app.HsrtestLabel.Text='NaN';
            else
                [p_sr,h_sr]=signrank(ThisPlotDataL4,ThisPlotDataR4);
                app.HsrtestLabel.Text=[num2str(h_sr) ' p(' num2str(p_sr) ')'];
                ColumnLabel{1,1} = 'Median';
                for collabeli = 1:length(SubjectN1)
                    ColumnLabel{1,collabeli+1} = ['Subject ' num2str(SubjectN1(collabeli))];
                end
                app.UITable.ColumnName=ColumnLabel;
                app.UITable.RowName={'Data of Plot 1';'Data of Plot 2';'R > L'};
                datarow1=[median(ThisPlotDataL4) ThisPlotDataL4];
                datarow2=[median(ThisPlotDataR4) ThisPlotDataR4];
                datarow3=datarow2>datarow1;
                app.UITable.Data = [datarow1;datarow2;datarow3];
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Indexs=load('Indexs_ref_5_median_option1n2.mat');
            app.TRPdata=load("TRP_ref_5_median_option1n2.mat");
            
            app.BandDropDown.Items={'All [4-45] Hz','Alpha','Beta'};
            app.BandDropDown.ItemsData = 1:3;
            app.BandDropDown.Value = 2;
            
            app.SubjectDropDown.Items = strsplit(num2str(1:28));
            app.SubjectDropDown.ItemsData = 1:28;
            app.SubjectDropDown.Value = 1;
            
            app.OptionDropDown.Items={'Option 1','Option 2'};
            app.OptionDropDown.ItemsData = 1:2;
            app.OptionDropDown.Value =2;
            
            app.RefDropDown.Items={'game 1','pre-game','min game'};
            app.RefDropDown.ItemsData=1:3;
            app.RefDropDown.Value = 2;
            
            app.TaskDropDown.Items = {'AU-Known','AU-New','Sketch', 'DwMT','Empty the Glass'};
            app.TaskDropDown.ItemsData = 1:5;
            app.TaskDropDown.Value = 3;
            
            app.StageDropDown.Items = {'All'};
            app.StageDropDown.ItemsData = 1;
            app.StageDropDown.Value = 1;
            
            app.AreaDropDown.Items = {'Left-Frontal','Right-Frontal','Left-Parietal','Right-Parietal','Left-Hemisphere','Right-Hemisphere','Front Area','Rear Area'};
            %'AF3','F7','F3','FC5','T7','P1','O1','O2','P2','T8','FC6','F4','F8','AF4',
            app.AreaDropDown.ItemsData=[1 2 3 4 5 6 7 8];
            app.AreaDropDown.Value = 5;
            
            app.BandDropDown_2.Items={'All [4-45] Hz','Alpha','Beta'};
            app.BandDropDown_2.ItemsData = 1:3;
            app.BandDropDown_2.Value = 2;
            
            app.OptionDropDown_2.Items={'Option 1','Option 2'};
            app.OptionDropDown_2.ItemsData = 1:2;
            app.OptionDropDown_2.Value =2;
            
            app.RefDropDown_2.Items={'game 1','pre-game','min game'};
            app.RefDropDown_2.ItemsData=1:3;
            app.RefDropDown_2.Value = 2;
            
            app.TaskDropDown_2.Items = {'AU-Known','AU-New','Sketch', 'DwMT','Empty the Glass'};
            app.TaskDropDown_2.ItemsData = 1:5;
            app.TaskDropDown_2.Value = 3;
            
            app.StageDropDown_2.Items = {'All'};
            app.StageDropDown_2.ItemsData = 1;
            app.StageDropDown_2.Value = 1;
            
            app.AreaDropDown_2.Items = {'Left-Frontal','Right-Frontal','Left-Parietal','Right-Parietal','Left-Hemisphere','Right-Hemisphere','Front Area','Rear Area'};
            app.AreaDropDown_2.ItemsData=[1 2 3 4 5 6 7 8];
            app.AreaDropDown_2.Value = 6;
            
            IndexDistr(app);
        end

        % Button pushed function: PlotDistributionButton
        function PlotDistributionButtonPushed(app, event)
            IndexDistr(app);
        end

        % Button pushed function: closeallplotsButton
        function closeallplotsButtonPushed(app, event)
            figHandles = findobj('type', 'figure', '-not', 'name', 'UIdistri');
            close(figHandles)
        end

        % Button pushed function: PlotTRPDistributionButton
        function PlotTRPDistributionButtonPushed(app, event)
            TRPDistr(app);
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            value = app.TaskDropDown.Value;
            app.TaskDropDown_2.Value = value;
            if value==4
                app.StageDropDown.Items = {'Reading','Selecting & Analyzing','Sketching'};
                app.StageDropDown.ItemsData = [1 2 4];
                app.StageDropDown.Value = 1;
                app.StageDropDown_2.Items = {'Reading','Selecting & Analyzing','Sketching'};
                app.StageDropDown_2.ItemsData = [1 2 4];
                app.StageDropDown_2.Value = 1;
            else
                app.StageDropDown.Items = {'All'};
                app.StageDropDown.ItemsData = 1;
                app.StageDropDown.Value = 1;
                app.StageDropDown_2.Items = {'All'};
                app.StageDropDown_2.ItemsData = 1;
                app.StageDropDown_2.Value = 1;
            end
            
        end

        % Value changed function: BandDropDown
        function BandDropDownValueChanged(app, event)
            value = app.BandDropDown.Value;
            app.BandDropDown_2.Value = value;
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            value = app.OptionDropDown.Value;
            app.OptionDropDown_2.Value=value;
        end

        % Value changed function: RefDropDown
        function RefDropDownValueChanged(app, event)
            value = app.RefDropDown.Value;
            app.RefDropDown_2.Value=value;
        end

        % Value changed function: StageDropDown
        function StageDropDownValueChanged(app, event)
            value = app.StageDropDown.Value;
            if app.TaskDropDown.Value==app.TaskDropDown_2.Value
            app.StageDropDown_2.Value=value;
            end
        end

        % Value changed function: AreaDropDown
        function AreaDropDownValueChanged(app, event)
            value = app.AreaDropDown.Value;
            app.AreaDropDown_2.Value=value;
%             if value<8
%                 app.AreaDropDown_2.Value=value+1;
%             else
%                 app.AreaDropDown_2.Value=value;
%             end
        end

        % Value changed function: ExcludeFCheckBox
        function ExcludeFCheckBoxValueChanged(app, event)
            value = app.ExcludeFCheckBox.Value;
            app.ExcludeFCheckBox_2.Value = value;
        end

        % Value changed function: ExcludeNECheckBox
        function ExcludeNECheckBoxValueChanged(app, event)
            value = app.ExcludeNECheckBox.Value;
            app.ExcludeNECheckBox_2.Value=value;
        end

        % Value changed function: ExcludeLACheckBox
        function ExcludeLACheckBoxValueChanged(app, event)
            value = app.ExcludeLACheckBox.Value;
            app.ExcludeLACheckBox_2.Value=value;
        end

        % Value changed function: TaskDropDown_2
        function TaskDropDown_2ValueChanged(app, event)
            value = app.TaskDropDown_2.Value;
            if value==4
                app.StageDropDown_2.Items = {'Reading','Selecting & Analyzing','Sketching'};
                app.StageDropDown_2.ItemsData = [1 2 4];
                app.StageDropDown_2.Value = 1;
            else
                app.StageDropDown_2.Items = {'All'};
                app.StageDropDown_2.ItemsData = 1;
                app.StageDropDown_2.Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIditri = uifigure('Visible', 'off');
            app.UIditri.Name = 'UI Figure';
            app.Plot1Panel = uipanel(app.UIditri);
            app.Plot1Panel.Title = 'Plot 1';
            app.TaskDropDownLabel = uilabel(app.Plot1Panel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.Plot1Panel);
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.StageDropDownLabel = uilabel(app.Plot1Panel);
            app.StageDropDownLabel.Text = 'Stage';
            app.StageDropDown = uidropdown(app.Plot1Panel);
            app.StageDropDown.ValueChangedFcn = createCallbackFcn(app, @StageDropDownValueChanged, true);
            app.BandDropDownLabel = uilabel(app.Plot1Panel);
            app.BandDropDownLabel.Text = 'Band';
            app.BandDropDown = uidropdown(app.Plot1Panel);
            app.BandDropDown.ValueChangedFcn = createCallbackFcn(app, @BandDropDownValueChanged, true);
            app.OptionDropDownLabel = uilabel(app.Plot1Panel);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.Plot1Panel);
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.RefDropDownLabel = uilabel(app.Plot1Panel);
            app.RefDropDownLabel.Text = 'Ref';
            app.RefDropDown = uidropdown(app.Plot1Panel);
            app.RefDropDown.ValueChangedFcn = createCallbackFcn(app, @RefDropDownValueChanged, true);
            app.SubjectDropDownLabel = uilabel(app.Plot1Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Plot1Panel);
            app.AreaDropDownLabel = uilabel(app.Plot1Panel);
            app.AreaDropDownLabel.Text = 'Area';
            app.AreaDropDown = uidropdown(app.Plot1Panel);
            app.AreaDropDown.ValueChangedFcn = createCallbackFcn(app, @AreaDropDownValueChanged, true);
            app.PlotTRPDistributionButton = uibutton(app.Plot1Panel, 'push');
            app.PlotTRPDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTRPDistributionButtonPushed, true);
            app.PlotTRPDistributionButton.Text = 'TRP Distribution of single subject';
            app.ExcludeFCheckBox = uicheckbox(app.Plot1Panel);
            app.ExcludeFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeFCheckBoxValueChanged, true);
            app.ExcludeFCheckBox.Text = 'Exclude Female Subjects';
            app.ExcludeNECheckBox = uicheckbox(app.Plot1Panel);
            app.ExcludeNECheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeNECheckBoxValueChanged, true);
            app.ExcludeNECheckBox.Text = 'Exclude non-engineer background Subjects';
            app.ExcludeLACheckBox = uicheckbox(app.Plot1Panel);
            app.ExcludeLACheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeLACheckBoxValueChanged, true);
            app.ExcludeLACheckBox.Text = 'Exclude lower activated Subjects';
            app.Plot2Panel = uipanel(app.UIditri);
            app.Plot2Panel.Title = 'Plot 2';
            app.TaskDropDown_2Label = uilabel(app.Plot2Panel);
            app.TaskDropDown_2Label.Text = 'Task';
            app.TaskDropDown_2 = uidropdown(app.Plot2Panel);
            app.TaskDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TaskDropDown_2ValueChanged, true);
            app.StageDropDown_2Label = uilabel(app.Plot2Panel);
            app.StageDropDown_2Label.Text = 'Stage';
            app.StageDropDown_2 = uidropdown(app.Plot2Panel);
            app.BandDropDown_2Label = uilabel(app.Plot2Panel);
            app.BandDropDown_2Label.Text = 'Band';
            app.BandDropDown_2 = uidropdown(app.Plot2Panel);
            app.OptionDropDown_2Label = uilabel(app.Plot2Panel);
            app.OptionDropDown_2Label.Text = 'Option';
            app.OptionDropDown_2 = uidropdown(app.Plot2Panel);
            app.RefDropDown_2Label = uilabel(app.Plot2Panel);
            app.RefDropDown_2Label.Text = 'Ref';
            app.RefDropDown_2 = uidropdown(app.Plot2Panel);
            app.AreaDropDown_2Label = uilabel(app.Plot2Panel);
            app.AreaDropDown_2Label.Text = 'Area';
            app.AreaDropDown_2 = uidropdown(app.Plot2Panel);
            app.ExcludeFCheckBox_2 = uicheckbox(app.Plot2Panel);
            app.ExcludeFCheckBox_2.Text = 'Exclude Female Subjects';
            app.ExcludeNECheckBox_2 = uicheckbox(app.Plot2Panel);
            app.ExcludeNECheckBox_2.Text = 'Exclude non-engineer background Subjects';
            app.ExcludeLACheckBox_2 = uicheckbox(app.Plot2Panel);
            app.ExcludeLACheckBox_2.Text = 'Exclude lower activated Subjects';
            app.PlotDistributionButton = uibutton(app.UIditri, 'push');
            app.PlotDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDistributionButtonPushed, true);
            app.PlotDistributionButton.Text = 'Plot Distribution';
            app.closeallplotsButton = uibutton(app.UIditri, 'push');
            app.closeallplotsButton.ButtonPushedFcn = createCallbackFcn(app, @closeallplotsButtonPushed, true);
            app.closeallplotsButton.Text = 'close all plots';
            app.signranktestLabel_2 = uilabel(app.UIditri);
            app.signranktestLabel_2.Text = '*selection change in Plot 1 will be automatically adapted in the panel Plot 2,  but not the other way around';
            app.StatisticalTestsssdsignificantstatisticaldifferencePanel = uipanel(app.UIditri);
            app.StatisticalTestsssdsignificantstatisticaldifferencePanel.Title = 'Statistical Tests (*s.s.d. significant statistical difference)';
            app.UITable = uitable(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.If1ssdexistbetweenthepairedsetsLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.If1ssdexistbetweenthepairedsetsLabel.Text = 'If 1, s.s.d exist between the paired sets';
            app.if0normallydistributeLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.if0normallydistributeLabel.Text = 'if 0, normally distribute';
            app.onlyifbothdatasetsarenormallydistributedif0equalvarianceLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.onlyifbothdatasetsarenormallydistributedif0equalvarianceLabel.Text = 'only if both datasets are normally distributed, if 0, equal variance';
            app.onlyifbothdatasethavenormaldistributionif1Label = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.onlyifbothdatasethavenormaldistributionif1Label.Text = 'only if both datasets are normally distributed, if 0, s.s.d between the two sets';
            app.ifnotthatbothdatasethavenormaldistributionif1Label = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.ifnotthatbothdatasethavenormaldistributionif1Label.Text = 'if not that both datasets are normally distributed, if 1, s.s.d between the two sets ';
            app.signranktestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.signranktestLabel.Text = 'signrank test';
            app.HsrtestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.HsrtestLabel.Text = 'Hsrtest';
            app.Hnorm2Label = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.Hnorm2Label.Text = 'Hnorm2';
            app.NormalDistributionLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.NormalDistributionLabel.Text = 'Normal Distribution';
            app.Hnorm1Label = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.Hnorm1Label.Text = 'Hnorm1';
            app.EqualVariancetestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.EqualVariancetestLabel.Text = 'Equal Variance test';
            app.HvarLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.HvarLabel.Text = 'Hvar';
            app.ttestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.ttestLabel.Text = 't-test';
            app.HttestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.HttestLabel.Text = 'Httest';
            app.WilcoxonranksumtestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.WilcoxonranksumtestLabel.Text = 'Wilcoxon rank sum test';
            app.HrktestLabel = uilabel(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.HrktestLabel.Text = 'Hrktest';
            app.NoPlotsCheckBox = uicheckbox(app.StatisticalTestsssdsignificantstatisticaldifferencePanel);
            app.NoPlotsCheckBox.Text = 'show statistical tests'' results without plots';
            app.signranktestLabel_3 = uilabel(app.UIditri);
            app.signranktestLabel_3.Text = '*Subject 8 and Subject 20 are left-hand dominant (excluded)';
            % Show the figure after all components are created
            app.UIditri.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IndexDistribution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIditri)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIditri)
        end
    end
end