classdef MainEEGshumin_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        uiEEGshumin                     matlab.ui.Figure
        eeglabMenu                      matlab.ui.container.Menu
        PlotRadarMapMenu                matlab.ui.container.Menu
        StatisticAnalysisMenu           matlab.ui.container.Menu
        TaskStudyMenu                   matlab.ui.container.Menu
        ResetMenu                       matlab.ui.container.Menu
        SubjectListPanel                matlab.ui.container.Panel
        FindUnprocessedDataButton       matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PlotDynamicMeanPanel            matlab.ui.container.Panel
        ChannelsPanel                   matlab.ui.container.Panel
        AccelarationButton              matlab.ui.control.StateButton
        GyroYButton                     matlab.ui.control.StateButton
        GyroXButton                     matlab.ui.control.StateButton
        Net3CheckBox                    matlab.ui.control.CheckBox
        Net2CheckBox                    matlab.ui.control.CheckBox
        Net1CheckBox                    matlab.ui.control.CheckBox
        O2Button                        matlab.ui.control.StateButton
        O1Button                        matlab.ui.control.StateButton
        P8Button                        matlab.ui.control.StateButton
        P7Button                        matlab.ui.control.StateButton
        T8Button                        matlab.ui.control.StateButton
        T7Button                        matlab.ui.control.StateButton
        FC6Button                       matlab.ui.control.StateButton
        FC5Button                       matlab.ui.control.StateButton
        F4Button                        matlab.ui.control.StateButton
        F3Button                        matlab.ui.control.StateButton
        F8Button                        matlab.ui.control.StateButton
        F7Button                        matlab.ui.control.StateButton
        AF4Button                       matlab.ui.control.StateButton
        AF3Button                       matlab.ui.control.StateButton
        SettingPanel                    matlab.ui.container.Panel
        PlotButton                      matlab.ui.control.Button
        RejectbythresholdCheckBox       matlab.ui.control.CheckBox
        TaGDropDown                     matlab.ui.control.DropDown
        TaskDropDown                    matlab.ui.control.DropDown
        ThresholdVEditField             matlab.ui.control.NumericEditField
        ThresholdVEditFieldLabel        matlab.ui.control.Label
        WindowLengthsEditField          matlab.ui.control.NumericEditField
        WindowLengthsEditFieldLabel     matlab.ui.control.Label
        UIAxesGyro                      matlab.ui.control.UIAxes
        UIAxesEEG                       matlab.ui.control.UIAxes
        DataProcessingPanel             matlab.ui.container.Panel
        UpdateAudiocheckCheckBox        matlab.ui.control.CheckBox
        UpdateSubbandsCheckBox          matlab.ui.control.CheckBox
        LabelDoneNumber                 matlab.ui.control.Label
        LabelLampProcess                matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        DoneLampLabel                   matlab.ui.control.Label
        DoneLamp                        matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        PendingLamp                     matlab.ui.control.Lamp
        PendingLampLabel                matlab.ui.control.Label
        MoveMeanCheckLamp               matlab.ui.control.Lamp
        MoveMeanCheckLampLabel          matlab.ui.control.Label
        AudioCheckLamp                  matlab.ui.control.Lamp
        AudioCheckLampLabel             matlab.ui.control.Label
        BSSCCALamp                      matlab.ui.control.Lamp
        BSSCCALampLabel                 matlab.ui.control.Label
        FilterHzLamp                    matlab.ui.control.Lamp
        FilterHzLampLabel               matlab.ui.control.Label
        DCoffsetRemovalLamp             matlab.ui.control.Lamp
        DCoffsetRemovalLampLabel        matlab.ui.control.Label
        LoadfromCSVLamp                 matlab.ui.control.Lamp
        LoadfromCSVLampLabel            matlab.ui.control.Label
        ChangeButton                    matlab.ui.control.Button
        RootFolderEditField             matlab.ui.control.EditField
        RootFolderEditFieldLabel        matlab.ui.control.Label
        AudioThresholdCheckPanel        matlab.ui.container.Panel
        CheckAudioThresholdButton       matlab.ui.control.Button
        RemoveSpeakCheckBox             matlab.ui.control.CheckBox
        DataProcessSettingPanel         matlab.ui.container.Panel
        CCAWindowShiftsEditField        matlab.ui.control.NumericEditField
        CCAWindowShiftsEditFieldLabel   matlab.ui.control.Label
        CCAWindowLengthsEditField       matlab.ui.control.NumericEditField
        CCAWindowLengthsEditFieldLabel  matlab.ui.control.Label
        FileCreatedTimePanel            matlab.ui.container.Panel
        Videomp4EditField               matlab.ui.control.EditField
        Videomp4EditFieldLabel          matlab.ui.control.Label
        EmotivcedEditField              matlab.ui.control.EditField
        EmotivcedEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        PathF % Description
        
        INFO % Description
        PlotAudioCheckApp % Description
        PlotRadarMapApp % Description
    end
    
    methods (Access = private)
        
        function SelectChannelNet(app)
            value1=app.Net1CheckBox.Value; value2=app.Net2CheckBox.Value; value3=app.Net3CheckBox.Value;
            channeltoplot=value1*[0 0 0 1 0 0 0 1 1 0 1 0 0 0]+value2*[1 0 0 0 0 0 0 0 0 0 0 1 1 1]+value3*[0 1 1 0 1 1 1 0 0 1 0 0 0 0];
            app.AF3Button.Value = channeltoplot(1);app.AF4Button.Value = channeltoplot(14);
            app.F7Button.Value  = channeltoplot(2);app.F8Button.Value  = channeltoplot(13);
            app.F3Button.Value  = channeltoplot(3);app.F4Button.Value  = channeltoplot(12);
            app.FC5Button.Value = channeltoplot(4);app.FC6Button.Value = channeltoplot(11);
            app.T7Button.Value  = channeltoplot(5);app.T8Button.Value  = channeltoplot(10);
            app.P7Button.Value  = channeltoplot(6);app.P8Button.Value  = channeltoplot(9);
            app.O1Button.Value  = channeltoplot(7);app.O2Button.Value  = channeltoplot(8);
        end
        
        function INFO = CurrentIDinfo(app)
            INFO.efctime=app.EmotivcedEditField.Value;   %emotiv file create time
            INFO.vctime=app.Videomp4EditField.Value;     %video created time
            CTime = {INFO.efctime, INFO.vctime};
            for efctime = 1:numel(CTime)
                if isempty(regexp(CTime{efctime}, '^[0-1][0-9]:[0-5][0-9]:[0-5][0-9]$','once'))
                    INFO.timeformat = 'wrong';
                    warndlg(['File Creation Time ' num2str(efctime) ' is in wrong format. Correct Format should be - HH:MM:SS'], 'Wrong Format')
                    winopen('E:/OneDrive - Politecnico di Milano/201920/PhD/Shumin_Research/Protocol_shumin/Data/RAWDATA/FileCreationTime.txt');
                    return
                end
                INFO.timeformat = 'right';
            end
            INFO.PN = app.SubjectIDEditField.Value;
            INFO.Age= app.AgeEditField.Value;
            INFO.Background=app.BackgroundDropDown.Value;
            INFO.Gender = app.GenderSwitch.Value;
            INFO.DominantHand = app.DominantHandSwitch.Value;
            INFO.FSeeg = num2str(app.ResampleFreqHzEditField.Value);
            INFO.BSSCCAwin = [num2str(app.CCAWindowLengthsEditField.Value) '_' num2str(app.CCAWindowShiftsEditField.Value)];
            INFO.EEGBfilter='4_45';
        end
        
        function SaveINFO(app)
            if exist(app.PathF.RawInfo,'file') ~= 2
                infofield=fieldnames(app.INFO);
                infostr=struct2cell(app.INFO);
                fid = fopen(app.PathF.RawInfo,'w');
                infotaskorder = ['taskorder - ' app.TaskOrder.Text];
                fprintf(fid,'%s\n', infotaskorder);
                for infoii=1:length(infofield)
                    infotext=[infofield(infoii),infostr(infoii)];
                    infomsg=strjoin(infotext, ' - ');
                    fprintf(fid,'%s\n', infomsg);
                end
                fclose(fid);
            end
        end
        
        function PathF = PrepareINFOnPath(app)
            % find path to save data
            PathF.root = app.RootFolderEditField.Value; %checked
            
            PathF.loc = [PathF.root 'MatlabCodes/emotiv14loc.ced'];%checked
            
            % Folder RawData and its subfolder/files
            PathF.Raw = [PathF.root 'RawData/'];%checked
            
            PathF.RawEmotiv = [PathF.Raw 'EmotivCSV/']; %checked
            PathF.RawVideo = [PathF.Raw 'VideoMP4/']; %checked
            
            % Folder ProcessedData and its subfolder/files
            PathF.PD = [PathF.root 'ProcessedData/'];%%Folder existed
            PathF.PDEEG =[PathF.PD 'EEG/Dataset.mat'];%%Folder existed
            PathF.PDEEGband =[PathF.PD 'EEG/FilterbandDataset.mat'];%%Folder existed
            PathF.PDvideo = [PathF.PD 'Video/' ];%%%Folder to be created
            
            %             PathF.PDvtimesect = mkfolder([PathF.PDvideo pNstr '_VTimeSection.mat']);%%%Folder to be created
            %             PathF.PDvthinkIdx = mkfolder([PathF.PDvideo pNstr '_ThinkTS.mat']);%%%Folder to be created
            %             PathF.PDvplotpara = mkfolder([PathF.PDvideo pNstr '_PlotAudioCheck.mat']);%%%Folder to be created
            %             PathF.PDvmat = [PathF.PDvideo pNstr '.mat'];%file
            %             PathF.RawCT = [PathF.Raw 'FileCreationTime.txt'];
            %             PathF.RawInfo = [mkfolder([PathF.Raw 'InfoTXT/']) pNstr '.txt'];%%Folder existed
            %
            %             PathF.Rawdata = [mkfolder([PathF.Raw 'Rawdata/']) pNstr '.mat'];%%Folder existed
            % %% Folder PlotCSV and its subfolder/files
            % PathF.PlotCSV = mkfolder([PathF.root 'PlotCSV/' pNstr '_']);%%Folder existed %% file to be created
            %% Folder Plots
            %PathF.Plots = mkfolder([PathF.root 'Plots/' pNstr '/' pNstr '_']);
            
        end
        
        function Datasets = ImportfromCSV(app,PNstr) %Data.rawAll Data.srate Data.ts1s Data.rawinterval
            % import data from csv file
            CSVinfo=dir([app.PathF.RawEmotiv 'Psychopy_' PNstr '*.00.csv']);
            path = CSVinfo.folder;
            file = CSVinfo.name;
            Datasets.ID=PNstr;
            RawDataAll = readmatrix([path '\' file],'Range','A2');
            % Data.rawAll = csvread(app.PathF.RawEmotiv,1,0); This excludes the first row
            RawDataEEG = RawDataAll(:,4:17);
            
            MarkerRefStart  = find(RawDataAll(:,23)==0)';
            MarkerTask1Start = find(RawDataAll(:,23)==1)';
            MarkerTask2Start = find(RawDataAll(:,23)==2)';
            MarkerTask3Start = find(RawDataAll(:,23)==3)';
            MarkerTask4Start = find(RawDataAll(:,23)==4)';
            MarkerTask5Start = find(RawDataAll(:,23)==5)';
            MarkerGameStart = find(RawDataAll(:,23)==6)';
            MarkerEnd       = find(RawDataAll(:,23)==9)';
                       
            Marker = sortrows([0 0 ones(1,5)*6 1 2 2 3 4 5;MarkerRefStart MarkerGameStart MarkerTask1Start MarkerTask2Start MarkerTask3Start MarkerTask4Start MarkerTask5Start]',2);                     
            Datasets.TaskOrder=getorderfromEEGmarker(Marker(:,1));
            
            Marker(:,3)=MarkerEnd;
            Marker=sortrows(Marker,1); 
            
            for markeri=1:13
                Datasets.Rawdata(markeri).s = RawDataEEG(Marker(markeri,2):Marker(markeri,3),:)';
            end
            
            function TaskOrder=getorderfromEEGmarker(Marker)
                TaskOrder=Marker;
                TaskOrder(TaskOrder==0)=[];
                TaskOrder(TaskOrder==6)=[];
                TaskOrder(TaskOrder==9)=[];
                TaskOrder(find(TaskOrder==2,1))=[];
            end
        end
        
%         function WriteEventList(app)            
%             fct.efctime=timeinsec(app.EmotivcedEditField.Value);%format emotiv file create time
%             fct.vctime=timeinsec(app.Videomp4EditField.Value);%format video created time
%             A = readmyfile(app.PathF.RawPhp,'%f%s%[^\n]');
%             taskorder=findsequence(A{1,3});
%             if exist(app.PathF.PDeventCheckList,'file') ~= 2 || exist(app.PathF.PDeventCuttimeEmotiv,'file') ~= 2 || exist(app.PathF.PDeventCuttimeVideo,'file') ~= 2
%                 MarkerList = readmyfile(app.PathF.MarkerListPath,'%d%s%[^\r]');
%                 eventrow=[1 30 31 35 36 38 39 41 42 44 3 17 5 16 47 50];
%                 for taskN = 1:length(eventrow)/2
%                     thistask = ['Task' num2str(taskN)];
%                     TaGstart = eventrow(2*taskN-1);
%                     TaGend = eventrow(2*taskN);
%                     for column = 1:3
%                         Task.(thistask){1,column}=MarkerList{1,column}(TaGstart:TaGend,1);
%                     end
%                 end
%                 ALLeventlist= cell(1,3);
%                 for column = 1:3
%                     ALLeventlist{1,column}(1:16,1) =Task.Task1{1,column}(1:16,1);
%                     ALLeventlist{1,column}(17:28,1)=Task.Task7{1,column};
%                     ALLeventlist{1,column}(29:42,1)=Task.Task1{1,column}(17:end,1);
%                 end
%                 cuttimerow = [5 29 32 42];
%                 for taskN=2:5
%                     thistask = ['Task' taskorder(taskN)];
%                     tasklen = length(Task.(thistask){1,1});
%                     for column = 1:3
%                         ALLeventlist{1,column}(end+1:end+15,1)=Task.Task6{1,column};
%                     end
%                     for column = 1:3
%                         ALLeventlist{1,column}(end+1:end+tasklen,1)=Task.(thistask){1,column};
%                     end
%                     cuttimerowtemp = [cuttimerow cuttimerow(end)+2 cuttimerow(end)+15 cuttimerow(end)+15+(2:tasklen)];
%                     cuttimerow = cuttimerowtemp;
%                 end
%                 ALLeventlist{1,column}(end+1:end+2,1)=Task.Task8{1,column};
%                 cuttimerow(4)=43;
%                 cuttimerow(end+1:end+4)=(cuttimerow(end)+1):(cuttimerow(end)+4);
%                 for column = 1:3
%                     ALLeventlist{1,column}(end+1:end+2,1)=MarkerList{1,column}(45:46,1);
%                 end
%                 % save this file to check if events are extract correctly
%                 k=1;kk=[];
%                 fid1 = fopen(app.PathF.PDeventCheckList,'w');
%                 for ii = 1:length(A{1,1})
%                     if strcmp(string(A{1,3}{ii,1}),string(ALLeventlist{1,3}{k,1}))
%                         fprintf(fid1,'%.4f\t', A{1,1}(ii));
%                         fprintf(fid1,'%s\n', A{1,3}{ii,1});
%                         ktemp=[kk ii];
%                         kk=ktemp;
%                         k=k+1;
%                     end
%                 end
%                 fclose(fid1);
%                 % events to create markers
%                 SyncA=A{1,1}(kk)-A{1,1}(1)+fct.lfctime+(0.5+app.Data.ts1s/128)-fct.efctime;
%                 fid2 = fopen(app.PathF.PDeventList,'w');
%                 fprintf(fid2,'%s\n', 'Latency	Type	EventN');
%                 for ii = 1:length(SyncA)
%                     fprintf(fid2,'%.4f\t', SyncA(ii));
%                     fprintf(fid2,'%s\t', ALLeventlist{1,2}{ii,1});
%                     fprintf(fid2,'%d\n', ALLeventlist{1,1}(ii));
%                 end
%                 fclose(fid2);
%                 %%Save EEG cuttime
%                 fid3 = fopen(app.PathF.PDeventCuttimeEmotiv,'w');
%                 for jj = 1:length(cuttimerow)
%                     ii=cuttimerow(jj);
%                     CutTimeEEG=SyncA(ii)+cos(mod(jj,2)*pi);
%                     fprintf(fid3,'%.4f\n', CutTimeEEG);
%                 end
%                 fclose(fid3);
%                 %Save Video/Audio cuttime
%                 SyncA2V=A{1,1}(kk)-A{1,1}(1)+fct.lfctime+(0.5+app.Data.ts1s/128)-fct.vctime;
%                 fid4 = fopen(app.PathF.PDeventCuttimeVideo,'w');
%                 for jj = 1:length(cuttimerow)
%                     ii=cuttimerow(jj);
%                     CutTimeVideo=SyncA2V(ii)+cos(jj*pi);
%                     fprintf(fid4,'%.4f\n', CutTimeVideo);
%                 end
%                 fclose(fid4);
%             end
%             function timeins=timeinsec(timeinh)
%                 % timeinhour ='HH:MM:SS'
%                 timeins=str2double(timeinh(1:2))*3600+str2double(timeinh(4:5))*60+str2double(timeinh(7:end));
%             end
%             function A = readmyfile(path,format)
%                 fide = fopen(path);
%                 A = textscan(fide,format);%MarkerList = readmyfile(app.PathF.MarkerListPath,'%d%s%[^\r]');
%                 fclose(fide);
%             end
%             function taskorder=findsequence(event)
%                 intropat = '\<introTa\d[A]*: autoDraw = True';%only task1 is introTA1 with capital A
%                 matchi=regexp(event,intropat,'match');
%                 matchi=matchi(~cellfun('isempty',matchi));
%                 taskorder='11111';
%                 for temp=1:length(matchi)
%                     taskorder(temp+1)=matchi{temp,1}{1,1}(8);
%                 end
%             end
%         end       
        
        
        function NameList = TaskName(app) %%% i is a 5 digit num i.e. 13254 is the sequence of the tasks
            NameList = strings([13,1]);
            for j = 1:5
                NameList(j*2-1,1) = ['G' num2str(j) '  '];
                if length(app.TaskOrder.Text)==1
                    NameList(j*2,1) = ['Ta' app.TaskOrder.Text{1,1}(j) ' '];
                else
                    NameList(j*2,1) = ['Ta' app.TaskOrder.Text(j) ' '];
                end
            end
            Ta2index = find( NameList(:,1)=='Ta2 ');
            afterTa2 = NameList(Ta2index+1:11-1,1);
            NameList(Ta2index,1)='Ta2a';
            NameList(Ta2index+1,1)='Ta2b';
            NameList(Ta2index+2:11,1)=afterTa2;
            NameList(12,1)='EO';
            NameList(13,1)='EC';
        end
        
        function ColorProcessLamp(app,lamps)
            ColorSet = [1 0 0; 0.93 0.69 0.13; 0 1 0];
            app.LoadfromCSVLamp.Color = ColorSet(lamps(1),:);
            app.DCoffsetRemovalLamp.Color = ColorSet(lamps(2),:);
            app.FilterHzLamp.Color = ColorSet(lamps(3),:);
            app.BSSCCALamp.Color = ColorSet(lamps(4),:);
            app.AudioCheckLamp.Color = ColorSet(lamps(5),:);
            app.MoveMeanCheckLamp.Color = ColorSet(lamps(6),:);
            pause(0.5);
        end
        
        function LoadNewVideo(app)
            if exist(app.PathF.PDvmat,'file') ~= 2
                disp(['Start loading Audio data for ID ' app.SubjectIDEditField.Value] );
                disp('--------------------------------------------------');
                if exist(app.PathF.RawVideo,'file')==2
                    [y1or2channel,Fs] = audioread(app.PathF.RawVideo);
                    y=y1or2channel(:,1); %take only the left channel if there are 2 channels
                    save(app.PathF.PDvmat, 'y', 'Fs');
                    disp(['Now video of participant' app.SubjectIDEditField.Value ' is saved as .mat in folder [' app.PathF.PDvideo ']']);
                    disp('--------------------------------------------------');
                else
                    warning(['cannot find video recording of participant' app.SubjectIDEditField.Value]);
                    return
                end
            end
        end
        
        function AudioCut = SegmentAudio(app)
            pNstr = app.SubjectIDEditField.Value;
            if exist(app.PathF.PDvthinkIdx,'file') ~= 2
                % if exist think file, it means audio are already segmented
                if exist(app.PathF.PDvtimesect,'file') ~=2
                    Audio=load(app.PathF.PDvmat);% loadfile has info of y and Fs
                    Vcuttime =round(load(app.PathF.PDeventCuttimeVideo)*Audio.Fs);
                    % if exist TaGaudio file, it means audio are already segmented,
                    % which could be used for cut off speaking section, but no need to
                    % calculated again
                    for Taii = 1:13
                        if Vcuttime(2*Taii)>length(Audio.y)
                            Vcuttime(2*Taii) = length(Audio.y);
                        end
                        Audiosample = Audio.y(Vcuttime(2*Taii-1): Vcuttime(2*Taii));
                        NAuPoint = length(Audiosample);
                        ampaudio.(['TaG' num2str(Taii)]) = abs(Audiosample);
                        authreshold=zeros(NAuPoint,1);
                        tstep = Audio.Fs*1.2;
                        for ti=1:tstep:NAuPoint
                            if ti+tstep-1<NAuPoint
                                authreshold(ti:ti+tstep-1)= max(ampaudio.(['TaG' num2str(Taii)])(ti:ti+tstep-1));
                            else
                                authreshold(ti:end)= max(ampaudio.(['TaG' num2str(Taii)])(ti:end));
                            end
                        end
                        AudioThreshold.(['TaG' num2str(Taii)])=mean(authreshold)/1.12;
                    end
                    Fs = Audio.Fs; Audio.AudioThreshold=AudioThreshold;Audio.ampaudio=ampaudio;
                    save(app.PathF.PDvtimesect,'Fs','ampaudio','AudioThreshold');%Audiosample
                    disp([ pNstr ' - (info of Fs, AudioAmplitude, AudioThreshold)is now saved']);
                    disp('--------------------------------------------------');
                else
                    disp([ pNstr ' - (info of Fs, AudioAmplitude, AudioThreshold) already exists']);
                    disp('--------------------------------------------------');
                    Audio = load(app.PathF.PDvtimesect);%'Fs','ampaudio','AudioThreshold'.Taii
                end
                disp([ pNstr ' - Think Timestamp is saving']);
                disp('--------------------------------------------------');
                for TaGii=1:13
                    ThinkTS.(['TaG' num2str(TaGii)])= findthinkIdx([-0.1 1],Audio,TaGii);%Talkspan = [-0.1 1];
                end
                Fs = Audio.Fs;
                AudioCut.ThinkTS = ThinkTS; AudioCut.Fs=Fs;
                save(app.PathF.PDvthinkIdx,'ThinkTS','Fs');
                disp([ pNstr ' - ThinkTimestamp is saved']);
                disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%');
            else
                disp([pNstr ' - ThinkTimestamp already exists']);
                AudioCut = load(app.PathF.PDvthinkIdx); %Fs,ThinkTS.(['TaG' num2str(Taii)])
                disp('--------------------------------------------------');
            end
            function ThinkTimestamp=findthinkIdx(Talkspan,Audio,TaGii)
                ampaudioi=Audio.ampaudio.(['TaG' num2str(TaGii)]);
                AudioThresholdi = Audio.AudioThreshold.(['TaG' num2str(TaGii)]);
                NAuPointi=length(ampaudioi);
                fmax=find(ampaudioi>AudioThresholdi);
                thinkIdxi = ones(1,NAuPointi);
                for fmaxi = 1:length(fmax)
                    idxi=fmax(fmaxi);
                    idxs = idxi+Talkspan(1)*Audio.Fs;
                    idxe = idxi+Talkspan(2)*Audio.Fs;
                    if idxs <= 0 %First second will be cutted
                        idxs=1;
                    elseif idxe > NAuPointi
                        idxe=NAuPointi;
                    end
                    thinkIdxi(idxs:idxe)=0;
                end
                thinkIdxi(1:Audio.Fs)=0;%First second will be cutted
                thinkIdxi(NAuPointi-Audio.Fs+1:NAuPointi)=0;%Last second will be cutted
                %[0 thinkIdx] add 0 at the beginning, if ThinkTimestamp(1)=0, ThinkTimestamp starts with 1
                %[thinkIdx 0] add 0 at the end, if ThinkTimestamp(end)= 1, ThinkTimestamp ends with NAupointi
                %ThinkTimestamp - start/end which timestamp are 0
                ThinkTimestamp = sort([strfind([0 thinkIdxi], [0 1])-1, strfind([thinkIdxi 0], [1 0])+1]);
                ThinkTimestamp(end+1) = NAuPointi;
            end
        end
        
        function AudioFreeEEG(app,path,AudioCut)
            Fs=AudioCut.Fs;
            Fseeg = app.ResampleFreqHzEditField.Value;
            NOaudio = app.RemoveSpeakCheckBox.Value;  NoAuEEGsp=zeros(1,11);
            eegAFhzlog.AllBand  =zeros(14,11);  eegAFhz.AllBand  =zeros(14,11);  eegAFhzlog.ThetaBand=zeros(14,11);  eegAFhz.ThetaBand=zeros(14,11);
            eegAFhzlog.Alpha1Band=zeros(14,11); eegAFhz.Alpha1Band=zeros(14,11); eegAFhzlog.Alpha2Band=zeros(14,11); eegAFhz.Alpha2Band=zeros(14,11);
            eegAFhzlog.AlphaBand=zeros(14,11);  eegAFhz.AlphaBand=zeros(14,11);  eegAFhzlog.BetaBand =zeros(14,11);  eegAFhz.BetaBand =zeros(14,11);
            eegAFhzlog.Beta1Band =zeros(14,11); eegAFhz.Beta1Band =zeros(14,11); eegAFhzlog.Beta2Band =zeros(14,11); eegAFhz.Beta2Band =zeros(14,11);
            eegAFhzlog.GammaBand=zeros(14,11);  eegAFhz.GammaBand=zeros(14,11);  eegAFhzlog.Beta3Band =zeros(14,11); eegAFhz.Beta3Band =zeros(14,11);
            eegAFhzlog.Gamma1Band=zeros(14,11); eegAFhz.Gamma1Band=zeros(14,11); eegAFhzlog.Gamma2Band=zeros(14,11); eegAFhz.Gamma2Band=zeros(14,11);
            for Taii = 1:11
                ThinkTSii = AudioCut.ThinkTS.(['TaG' num2str(Taii)]);
                eegdatapower = load([app.PathF.PDEEGxPower num2str(Taii) 'xPower.mat']);% load eegdatapower.BAND
                subbandN = fieldnames(eegdatapower);
                for subbandi = 1:length(subbandN)
                    eegbandpower = eegdatapower.(char(subbandN(subbandi)));
                    eegaudiofree = eegbandpower;
                    Ncutpoint = 0;
                    if NOaudio == 1
                        for Talktsi = 2:2:length(ThinkTSii)-2 % the last element in ThinkTS is the length of audio sample
                            Talks = floor(ThinkTSii(Talktsi)*Fseeg/Fs)+1;
                            Talke = ceil(ThinkTSii(Talktsi+1)*Fseeg/Fs);
                            if Talke>length(eegbandpower)
                                Talke=length(eegbandpower);
                            end
                            eegaudiofree(Talks:Talke,:)=0;
                            Ncutpoint=Talke-Talks+1+Ncutpoint;
                        end
                        Talke1 = floor(ThinkTSii(1)*Fseeg/Fs);
                        eegaudiofree(1:Talke1,:)=0;
                        Talksend = floor(ThinkTSii(end-1)*Fseeg/Fs);
                        eegaudiofree(Talksend:end,:)=0;
                        Ncutpoint = Talke1+length(eegaudiofree(Talksend:end,1))+Ncutpoint;
                    end
                    NoAuEEGsp(Taii) = length(eegaudiofree)-Ncutpoint;
                    eegAFhz.(char(subbandN(subbandi)))(:,Taii)=sum(abs(eegaudiofree).^2)/NoAuEEGsp(Taii);
                    eegAFhzlog.(char(subbandN(subbandi)))(:,Taii)=log(eegAFhz.(char(subbandN(subbandi)))(:,Taii));
                end
            end
            save(path,'eegAFhz','eegAFhzlog','NoAuEEGsp');
        end
        
        
        function DataInAxes(app)
            [xPowerOrder,timeUnit]=MakeTaskinOrder(app);
            taskj =  num2str(xPowerOrder(str2double(app.TaskDropDown.Value)));
            
            range = 127*app.WindowLengthsEditField.Value; % for other
            xPower = load([app.PathF.PDEEGxPower taskj 'xPower.mat']);
            xPower=xPower.AllBand;
            if app.RejectbythresholdCheckBox.Value==1
                threshold=app.ThresholdVEditField.Value;
                xPower = RejectHighPowerWindow(xPower,range,threshold);
            end
            movemean = movmean(xPower.^2,range);
            timex=(1:length(movemean))/127/timeUnit(end);
            CtP = [app.AF3Button.Value,app.F7Button.Value,app.F3Button.Value, app.FC5Button.Value ,...
                app.T7Button.Value, app.P7Button.Value,app.O1Button.Value, app.O2Button.Value, ...
                app.P8Button.Value, app.T8Button.Value,app.FC6Button.Value,app.F4Button.Value,...
                app.F8Button.Value, app.AF4Button.Value];
            ChanList={'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            if sum(CtP)==0
                app.AF3Button.Value = 1;
                CtP(1)=1;
            end
            plot(app.UIAxesEEG,timex,movemean(:,CtP==1))
            app.UIAxesEEG.XLim = [0 timeUnit(str2double(app.TaskDropDown.Value))];
            app.UIAxesEEG.YLim = [0 500];
            app.UIAxesEEG.Title.String = ['Subject ' app.SubjectIDEditField.Value];
            legend(app.UIAxesEEG,ChanList{1,CtP==1});
            
            function [xPowerOrder,timeUnit]=MakeTaskinOrder(app)
                taskorder = char(app.TaskOrder.Text);
                Taidx = [1 find(taskorder == '2') find(taskorder == '3') find(taskorder == '4') find(taskorder == '5')];
                FileOrder=ones(11,1);
                FileOrder([1 3 6 8 10])=Taidx*2-1;
                FileOrder([2 4 7 9 11])=Taidx*2;FileOrder(5)=Taidx(2)*2+1;
                for ii = 3:5
                    if Taidx(ii)>Taidx(2)
                        FileOrder([ii*2 ii*2+1])=FileOrder([ii*2 ii*2+1])+1;
                    end
                end
                if strcmp(app.TaGDropDown.Value,'1')
                    xPowerOrder=FileOrder([1 3 3 6 8 10]);
                    timeUnit=[ones(1,6)*30 1];
                    app.UIAxesEEG.XLabel.String = 'time (sec)';
                elseif strcmp(app.TaGDropDown.Value,'2')
                    xPowerOrder=FileOrder([2 4 5 7 9 11]);
                    timeUnit=[0.5 1 2 3 10 3 60];
                    app.UIAxesEEG.XLabel.String = 'time (min)';
                end
            end
            function eegdatapower = RejectHighPowerWindow(eegdatapower,range,threshold)
                movemeanrej = movmean(eegdatapower.^2,range);
                for times=1:length(movemeanrej)
                    if max(movemeanrej(times,:))>threshold
                        if times+range < length(movemeanrej)
                            eegdatapower(times:(times+range),:)=nan;
                        else
                            eegdatapower(times:end,:)=nan;
                        end
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Net1CheckBox
        function Net1CheckBoxValueChanged(app, event)
            SelectChannelNet(app);
        end

        % Value changed function: Net2CheckBox
        function Net2CheckBoxValueChanged(app, event)
            SelectChannelNet(app);
        end

        % Value changed function: Net3CheckBox
        function Net3CheckBoxValueChanged(app, event)
            SelectChannelNet(app);
        end

        % Button pushed function: CheckAudioThresholdButton
        function CheckAudioThresholdButtonPushed(app, event)
    app.PathF=PrepareINFOnPath(app);
    LoadNewVideo(app);
    if exist(app.PathF.PDeventCuttimeVideo,'file')~=2
        WriteEventList(app);
    end
    if exist(app.PathF.PDvplotpara,'file')~=2
        SegmentAudio(app);
    end
    app.PlotAudioCheckApp = PlotAudioCheck(app,app.SubjectIDEditField.Value,app.TaskOrder.Text,app.PathF.PDvtimesect);
    
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
    app.RootFolderEditField.Value=[uigetdir('E:/OneDrive - Politecnico di Milano/202021/AdR/Data/') '/'] ;
        end

        % Menu selected function: eeglabMenu
        function eeglabMenuSelected(app, event)
    eeglab;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
    DataInAxes(app);
        end

        % Menu selected function: PlotRadarMapMenu
        function PlotRadarMapMenuSelected(app, event)
    app.PathF=PrepareINFOnPath(app);
    passfolder = app.PathF.root;
    if exist([app.PathF.PDEEGAF '.mat'],'file')==2 && exist([app.PathF.PDEEGAF '_withS.mat'],'file')==2
        PassInfo={app.SubjectIDEditField.Value,app.AgeEditField.Value,app.GenderSwitch.Value,app.DominantHandSwitch.Value,app.BackgroundDropDown.Value,app.TaskOrder.Text,passfolder};
        %                 app.PlotRadarMapApp = RadarPlotshumin(app,app.SubjectIDEditField.Value,app.TaskOrder.Text,folderEEGAF);
        app.PlotRadarMapApp = RadarPlotshumin(app,PassInfo);
    else
        warndlg('There no file plot radar map, please run signal processing first','No such file');
        return
    end
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            ResetAns = questdlg('Are you sure to reset?','Reset','Yes','No','No');
            if strcmp(ResetAns,'Yes')
                app.PathF = [];
                app.CCAWindowLengthsEditField.Value = 2.5; app.CCAWindowShiftsEditField.Value = 1.2;
                app.RootFolderEditField.Value = 'E:/OneDrive - Politecnico di Milano/202021/AdR/Data/';
                app.UpdateSubbandsCheckBox.Value = 0;
                app.LabelDoneNumber.Text ='0//13';
                ColorProcessLamp(app,[2 2 2 2 2 2]);
                
                app.RemoveSpeakCheckBox.Value = 0;
                
                
                app.WindowLengthsEditField.Value = 10; app.ThresholdVEditField.Value = 250;
                app.TaGDropDown.Value = '1'; app.TaskDropDown.Value = '1';
                app.RejectbythresholdCheckBox.Value = 0;
                app.Net1CheckBox.Value = 0;app.Net2CheckBox.Value = 0;app.Net3CheckBox.Value = 0; SelectChannelNet(app);
                cla(app.UIAxesEEG); app.UIAxesEEG.Title.String = 'EEG'; app.UIAxesEEG.XLabel.String = 'Time (sec)';
                cla(app.UIAxesGyro);
            end
        end

        % Value changed function: UpdateSubbandsCheckBox
        function UpdateSubbandsCheckBoxValueChanged(app, event)
    value = app.UpdateSubbandsCheckBox.Value;
    if value == 1
        app.UpdateAudiocheckCheckBox.Value = 1;
    end
        end

        % Menu selected function: StatisticAnalysisMenu
        function StatisticAnalysisMenuSelected(app, event)
    
        end

        % Button pushed function: FindUnprocessedDataButton
        function FindUnprocessedDataButtonPushed(app, event)
            app.PathF = PrepareINFOnPath(app);
            app.UITable.Data=[];
            %find in the raw data folder all subjects id code
            RawDataFiles=dir([app.PathF.RawEmotiv '*00.csv']);
            if exist(app.PathF.PDEEG,'file') ==2
                ProcessedData=load(app.PathF.PDEEG);
                removei=[];
                for ii=1:length(RawDataFiles)
                    for jj=1:length(ProcessedData.Datasets)
                        if strcmp(ProcessedData.Datasets(jj).ID, RawDataFiles(ii).name(10:12))
                            removei=[removei ii];%#ok
                            break
                        end
                    end
                end
                RawDataFiles(removei)=[];
            end
            
            for ii=1:length(RawDataFiles)
                app.UITable.Data(ii,:)=str2num(['1' RawDataFiles(ii).name(10:12)]);%#ok
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
%             eeglab
%             figHandles = findobj('type', 'figure', '-not', 'name', 'uiEEGshumin');
%             close(figHandles)
            
            if app.UpdateAudiocheckCheckBox.Value==1
                UpdateBandAns= questdlg('Are you sure to update subbands for all the participants?','Update','Yes','No','No');
                if strcmp(UpdateBandAns,'Yes')
                    RawDataFiles=dir([app.PathF.RawEmotiv '*00.csv']);
                    for rawfilei=1:length(RawDataFiles)
                        app.UITable.Data(rawfilei,:)=str2num(['1' RawDataFiles(rawfilei).name(10:12)]);%#ok
                    end                    
                end
            end
            subjectN=length(app.UITable.Data);
            
            for subjecti=1:subjectN
                subjectid=app.UITable.Data(subjecti);
                app.LabelDoneNumber.Text = ' 0//13' ;
                ColorProcessLamp(app,[1 2 2 2 2 2]);
                
                Datasets = ImportfromCSV(app,idtransform(subjectid)); %Datasets.Raw Datasets.Marker
                BandDatasets.ID=Datasets.ID;
                ColorProcessLamp(app,[3 2 2 2 2 2]);
                
                for subseti=1:13
                    app.LabelDoneNumber.Text = [' ' num2str(subseti) '/13'] ;
                    EEG = pop_importdata('dataformat','array','nbchan',0,'data',Datasets.Rawdata(subseti).s,'srate',128,'pnts',0,'xmin',0,'chanlocs',app.PathF.loc);
                    
                    %% DC offset Removal
                    EEGdcrm=dcremove(128,EEG,app);
                    Datasets.DCoffdata(subseti).s = EEGdcrm.data;
                    
                    %% Filter [filterband 4 45]
                    EEGfiltered = filterdataset(EEGdcrm,[4 45],app);%transition band width min([2 matDf]) shumin_edited
                    Datasets.Filtereddata(subseti).s = EEGfiltered.data;
                    
                    %% BSSCCA
                    [EEGbsscca,EEGbssccaBand]=BSSCCAeeg(EEGfiltered,4,app);
                    Datasets.BSSCCAdata(subseti).s = EEGbsscca.data;
                    BandDatasets.TaG(subseti)=EEGbssccaBand;
                                        
                    %% Median?


                end
                if exist(app.PathF.PDEEG,'file')~=2
                    save(app.PathF.PDEEG,'Datasets');
                else
                    m = matfile(app.PathF.PDEEG,'Writable',true);
                    m.Datasets(end+1,1)=Datasets;
                end
                if exist(app.PathF.PDEEGband,'file')~=2
                    save(app.PathF.PDEEGband,'BandDatasets');
                else
                    m = matfile(app.PathF.PDEEGband,'Writable',true);
                    m.BandDatasets(end+1,1)=BandDatasets;
                end
            end
            
            function EEGdcrm=dcremove(fs,EEG,app) %eegdata size (14*length)
                ColorProcessLamp(app,[3 1 2 2 2 2]);
                EEGdcrm=EEG;
                eegdata=EEG.data;
                IIR_TC = fs*2; %2 second time constant- adjust as required
                [rows, cols]=size(eegdata);
                if cols == 14
                    eegdata=eegdata';
                    [rows, cols]=size(eegdata);
                end
                EEGdcrm.data = zeros(rows,cols);
                back= eegdata(:,1);
                for ii = 2 : cols
                    back = (back*(IIR_TC-1) + eegdata(:,ii)) / IIR_TC;
                    EEGdcrm.data(:,ii)=eegdata(:,ii)- back;
                end
                ColorProcessLamp(app,[3 3 2 2 2 2]);
            end
            function EEGfiltered = filterdataset(EEG,filterband,app)
                ColorProcessLamp(app,[3 3 1 2 2 2]);
                EEGfiltered = pop_eegfiltnew(EEG, 'locutoff',filterband(1),'hicutoff',filterband(2),'plotfreqz',0);
                EEGfiltered.setname=[EEG.setname 'f'];
                ColorProcessLamp(app,[3 3 3 2 2 2]);
            end
            
            function [EEGbsscca,EEGbssccaBand]=BSSCCAeeg(EEG,Ncomp,app)
                ColorProcessLamp(app,[3 3 3 1 2 2]);
                EEGbsscca=EEG;
                EEGbsscca = pop_autobssemg(EEGbsscca, 1, 0.3, 'bsscca', {'eigratio', 1000000}, 'emg_psd', {'ratio', 10,'fs', 128,'femg', 15,'estimator',spectrum.welch,'range', [0  Ncomp]});%#ok
                EEGbsscca = pop_autobsseog(EEGbsscca, app.CCAWindowLengthsEditField.Value, app.CCAWindowShiftsEditField.Value, 'bsscca', {'eigratio', 1000000}, 'eog_fd', {'range',[1  Ncomp]});
                EEGbssccaBand = FilterBSSCCA(EEGbsscca);
                ColorProcessLamp(app,[3 3 3 3 2 2]);
                
                function EEGbssccaBand=FilterBSSCCA(EEGbsscca)
                    EEGbssccatheta = pop_eegfiltnew(EEGbsscca,'locutoff',4,'hicutoff',7);%theta
                    EEGbssccaBand.thetaband  = EEGbssccatheta.data;
                    EEGbssccaalpha = pop_eegfiltnew(EEGbsscca, 'locutoff',7,'hicutoff',13);%alpha
                    EEGbssccaBand.alphaband  = EEGbssccaalpha.data;
                    EEGbssccabeta = pop_eegfiltnew(EEGbsscca, 'locutoff',13,'hicutoff',28);%beta
                    EEGbssccaBand.betaband  = EEGbssccabeta.data;
                    EEGbssccagamma = pop_eegfiltnew(EEGbsscca, 'locutoff',28,'hicutoff',45);%gamma
                    EEGbssccaBand.gammaband  = EEGbssccagamma.data;
                    EEGbssccaalpha1 = pop_eegfiltnew(EEGbsscca, 'locutoff',7,'hicutoff',10,'plotfreqz',0);%alpha1
                    EEGbssccaBand.alphaband1  = EEGbssccaalpha1.data;
                    EEGbssccaalpha2 = pop_eegfiltnew(EEGbsscca, 'locutoff',10,'hicutoff',13,'plotfreqz',0);%alpha2
                    EEGbssccaBand.alphaband2  = EEGbssccaalpha2.data;
                    EEGbssccabeta1 = pop_eegfiltnew(EEGbsscca, 'locutoff',13,'hicutoff',16,'plotfreqz',0);%beta1
                    EEGbssccaBand.betaband1  = EEGbssccabeta1.data;
                    EEGbssccabeta2 = pop_eegfiltnew(EEGbsscca, 'locutoff',16,'hicutoff',20,'plotfreqz',0);%beta2
                    EEGbssccaBand.betaband2  = EEGbssccabeta2.data;
                    EEGbssccabeta3 = pop_eegfiltnew(EEGbsscca, 'locutoff',20,'hicutoff',28,'plotfreqz',0);%beta3
                    EEGbssccaBand.betaband3  = EEGbssccabeta3.data;
                    EEGbssccagamma1 = pop_eegfiltnew(EEGbsscca, 'locutoff',28,'hicutoff',36,'plotfreqz',0);%gamma1
                    EEGbssccaBand.gammaband1  = EEGbssccagamma1.data;
                    EEGbssccagamma2 = pop_eegfiltnew(EEGbsscca, 'locutoff',36,'hicutoff',45,'plotfreqz',0);%gamma2
                    EEGbssccaBand.gammaband2  = EEGbssccagamma2.data;
                end
            end
            
            function pnstr=idtransform(num)
                pnstr=num2str(num);
                pnstr=pnstr(2:end);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.uiEEGshumin = uifigure('Visible', 'off');
            app.uiEEGshumin.Name = 'uiEEGshumin';
            app.eeglabMenu = uimenu(app.uiEEGshumin);
            app.eeglabMenu.MenuSelectedFcn = createCallbackFcn(app, @eeglabMenuSelected, true);
            app.eeglabMenu.Text = 'eeglab    ';
            app.PlotRadarMapMenu = uimenu(app.uiEEGshumin);
            app.PlotRadarMapMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotRadarMapMenuSelected, true);
            app.PlotRadarMapMenu.Text = 'Plot Radar Map   ';
            app.StatisticAnalysisMenu = uimenu(app.uiEEGshumin);
            app.StatisticAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @StatisticAnalysisMenuSelected, true);
            app.StatisticAnalysisMenu.Text = 'Statistic Analysis   ';
            app.TaskStudyMenu = uimenu(app.uiEEGshumin);
            app.TaskStudyMenu.Text = 'Task Study     ';
            app.ResetMenu = uimenu(app.uiEEGshumin);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset    ';
            app.FileCreatedTimePanel = uipanel(app.uiEEGshumin);
            app.FileCreatedTimePanel.Title = 'File Created Time';
            app.EmotivcedEditFieldLabel = uilabel(app.FileCreatedTimePanel);
            app.EmotivcedEditFieldLabel.Text = 'Emotiv.ced:';
            app.EmotivcedEditField = uieditfield(app.FileCreatedTimePanel, 'text');
            app.EmotivcedEditField.Value = '00:00:00';
            app.Videomp4EditFieldLabel = uilabel(app.FileCreatedTimePanel);
            app.Videomp4EditFieldLabel.Text = 'Video.mp4:';
            app.Videomp4EditField = uieditfield(app.FileCreatedTimePanel, 'text');
            app.Videomp4EditField.Value = '00:00:00';
            app.DataProcessSettingPanel = uipanel(app.uiEEGshumin);
            app.DataProcessSettingPanel.Title = 'Data Process Setting';
            app.CCAWindowLengthsEditFieldLabel = uilabel(app.DataProcessSettingPanel);
            app.CCAWindowLengthsEditFieldLabel.Text = 'CCA Window Length (s)';
            app.CCAWindowLengthsEditField = uieditfield(app.DataProcessSettingPanel, 'numeric');
            app.CCAWindowLengthsEditField.Value = 2.5;
            app.CCAWindowShiftsEditFieldLabel = uilabel(app.DataProcessSettingPanel);
            app.CCAWindowShiftsEditFieldLabel.Text = 'CCA Window Shift (s)';
            app.CCAWindowShiftsEditField = uieditfield(app.DataProcessSettingPanel, 'numeric');
            app.CCAWindowShiftsEditField.Value = 1.2;
            app.AudioThresholdCheckPanel = uipanel(app.uiEEGshumin);
            app.AudioThresholdCheckPanel.Title = 'Audio Threshold Check';
            app.RemoveSpeakCheckBox = uicheckbox(app.AudioThresholdCheckPanel);
            app.RemoveSpeakCheckBox.Text = 'Remove Speak';
            app.CheckAudioThresholdButton = uibutton(app.AudioThresholdCheckPanel, 'push');
            app.CheckAudioThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAudioThresholdButtonPushed, true);
            app.CheckAudioThresholdButton.Text = 'Check Audio Threshold';
            app.DataProcessingPanel = uipanel(app.uiEEGshumin);
            app.DataProcessingPanel.Title = 'Data Processing';
            app.RootFolderEditFieldLabel = uilabel(app.DataProcessingPanel);
            app.RootFolderEditFieldLabel.Text = 'Root Folder:';
            app.RootFolderEditField = uieditfield(app.DataProcessingPanel, 'text');
            app.RootFolderEditField.Value = 'E:/OneDrive - Politecnico di Milano/202021/AdR/Data/';
            app.ChangeButton = uibutton(app.DataProcessingPanel, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.LoadfromCSVLampLabel = uilabel(app.DataProcessingPanel);
            app.LoadfromCSVLampLabel.Text = 'Load from CSV';
            app.LoadfromCSVLamp = uilamp(app.DataProcessingPanel);
            app.DCoffsetRemovalLampLabel = uilabel(app.DataProcessingPanel);
            app.DCoffsetRemovalLampLabel.Text = 'DC offset Removal';
            app.DCoffsetRemovalLamp = uilamp(app.DataProcessingPanel);
            app.FilterHzLampLabel = uilabel(app.DataProcessingPanel);
            app.FilterHzLampLabel.Text = 'Filter [4-45] Hz';
            app.FilterHzLamp = uilamp(app.DataProcessingPanel);
            app.BSSCCALampLabel = uilabel(app.DataProcessingPanel);
            app.BSSCCALampLabel.Text = 'BSSCCA';
            app.BSSCCALamp = uilamp(app.DataProcessingPanel);
            app.AudioCheckLampLabel = uilabel(app.DataProcessingPanel);
            app.AudioCheckLampLabel.Text = 'Audio Check';
            app.AudioCheckLamp = uilamp(app.DataProcessingPanel);
            app.MoveMeanCheckLampLabel = uilabel(app.DataProcessingPanel);
            app.MoveMeanCheckLampLabel.Text = 'Move Mean Check';
            app.MoveMeanCheckLamp = uilamp(app.DataProcessingPanel);
            app.PendingLampLabel = uilabel(app.DataProcessingPanel);
            app.PendingLampLabel.Text = 'Pending';
            app.PendingLamp = uilamp(app.DataProcessingPanel);
            app.RunningLamp = uilamp(app.DataProcessingPanel);
            app.RunningLampLabel = uilabel(app.DataProcessingPanel);
            app.RunningLampLabel.Text = 'Running';
            app.DoneLamp = uilamp(app.DataProcessingPanel);
            app.DoneLampLabel = uilabel(app.DataProcessingPanel);
            app.DoneLampLabel.Text = 'Done';
            app.RUNButton = uibutton(app.DataProcessingPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.LabelLampProcess = uilabel(app.DataProcessingPanel);
            app.LabelLampProcess.Text = '>>>>>>>>>             >>>>>>>>>             >>>>>>>>>             >>>>>>>>>             >>>>>>>>>';
            app.LabelDoneNumber = uilabel(app.DataProcessingPanel);
            app.LabelDoneNumber.Text = ' 0/13';
            app.UpdateSubbandsCheckBox = uicheckbox(app.DataProcessingPanel);
            app.UpdateSubbandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateSubbandsCheckBoxValueChanged, true);
            app.UpdateSubbandsCheckBox.Text = 'Update Subbands';
            app.UpdateAudiocheckCheckBox = uicheckbox(app.DataProcessingPanel);
            app.UpdateAudiocheckCheckBox.Text = 'Update Audio check';
            app.PlotDynamicMeanPanel = uipanel(app.uiEEGshumin);
            app.PlotDynamicMeanPanel.Title = 'Plot Dynamic Mean';
            app.UIAxesEEG = uiaxes(app.PlotDynamicMeanPanel);
            title(app.UIAxesEEG, 'EEG')
            xlabel(app.UIAxesEEG, 'Time (sec)')
            ylabel(app.UIAxesEEG, 'Power (\muV^2)')
            app.UIAxesGyro = uiaxes(app.PlotDynamicMeanPanel);
            title(app.UIAxesGyro, 'Gyro X & Gyro Y')
            xlabel(app.UIAxesGyro, 'Time (sec)')
            ylabel(app.UIAxesGyro, 'zscored Power')
            app.SettingPanel = uipanel(app.PlotDynamicMeanPanel);
            app.SettingPanel.Title = '    Setting';
            app.WindowLengthsEditFieldLabel = uilabel(app.SettingPanel);
            app.WindowLengthsEditFieldLabel.Text = 'Window Length(s)';
            app.WindowLengthsEditField = uieditfield(app.SettingPanel, 'numeric');
            app.WindowLengthsEditField.Value = 10;
            app.ThresholdVEditFieldLabel = uilabel(app.SettingPanel);
            app.ThresholdVEditFieldLabel.Text = 'Threshold (μV^2)';
            app.ThresholdVEditField = uieditfield(app.SettingPanel, 'numeric');
            app.ThresholdVEditField.Value = 250;
            app.TaskDropDown = uidropdown(app.SettingPanel);
            app.TaskDropDown.Items = {'2-letters back', 'AU - Known', 'AU - New', 'Sketch', 'Design with MT', 'Empty Glass'};
            app.TaskDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.TaskDropDown.Value = '1';
            app.TaGDropDown = uidropdown(app.SettingPanel);
            app.TaGDropDown.Items = {'Game Before Task', 'Task'};
            app.TaGDropDown.ItemsData = {'1', '2'};
            app.TaGDropDown.Value = '1';
            app.RejectbythresholdCheckBox = uicheckbox(app.SettingPanel);
            app.RejectbythresholdCheckBox.Text = 'Reject by threshold';
            app.PlotButton = uibutton(app.SettingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ChannelsPanel = uipanel(app.PlotDynamicMeanPanel);
            app.ChannelsPanel.Title = '    Channels';
            app.AF3Button = uibutton(app.ChannelsPanel, 'state');
            app.AF3Button.Text = 'AF3';
            app.AF4Button = uibutton(app.ChannelsPanel, 'state');
            app.AF4Button.Text = 'AF4';
            app.F7Button = uibutton(app.ChannelsPanel, 'state');
            app.F7Button.Text = 'F7';
            app.F8Button = uibutton(app.ChannelsPanel, 'state');
            app.F8Button.Text = 'F8';
            app.F3Button = uibutton(app.ChannelsPanel, 'state');
            app.F3Button.Text = 'F3';
            app.F4Button = uibutton(app.ChannelsPanel, 'state');
            app.F4Button.Text = 'F4';
            app.FC5Button = uibutton(app.ChannelsPanel, 'state');
            app.FC5Button.Text = 'FC5';
            app.FC6Button = uibutton(app.ChannelsPanel, 'state');
            app.FC6Button.Text = 'FC6';
            app.T7Button = uibutton(app.ChannelsPanel, 'state');
            app.T7Button.Text = 'T7';
            app.T8Button = uibutton(app.ChannelsPanel, 'state');
            app.T8Button.Text = 'T8';
            app.P7Button = uibutton(app.ChannelsPanel, 'state');
            app.P7Button.Text = 'P7';
            app.P8Button = uibutton(app.ChannelsPanel, 'state');
            app.P8Button.Text = 'P8';
            app.O1Button = uibutton(app.ChannelsPanel, 'state');
            app.O1Button.Text = 'O1';
            app.O2Button = uibutton(app.ChannelsPanel, 'state');
            app.O2Button.Text = 'O2';
            app.Net1CheckBox = uicheckbox(app.ChannelsPanel);
            app.Net1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net1CheckBoxValueChanged, true);
            app.Net1CheckBox.Text = 'Net 1';
            app.Net2CheckBox = uicheckbox(app.ChannelsPanel);
            app.Net2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net2CheckBoxValueChanged, true);
            app.Net2CheckBox.Text = 'Net 2';
            app.Net3CheckBox = uicheckbox(app.ChannelsPanel);
            app.Net3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Net3CheckBoxValueChanged, true);
            app.Net3CheckBox.Text = 'Net 3';
            app.GyroXButton = uibutton(app.ChannelsPanel, 'state');
            app.GyroXButton.Text = 'Gyro X';
            app.GyroYButton = uibutton(app.ChannelsPanel, 'state');
            app.GyroYButton.Text = 'Gyro Y';
            app.AccelarationButton = uibutton(app.ChannelsPanel, 'state');
            app.AccelarationButton.Text = 'Accelaration';
            app.SubjectListPanel = uipanel(app.uiEEGshumin);
            app.SubjectListPanel.Title = 'Subject List';
            app.UITable = uitable(app.SubjectListPanel);
            app.UITable.ColumnName = {'Subject ID'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.FindUnprocessedDataButton = uibutton(app.SubjectListPanel, 'push');
            app.FindUnprocessedDataButton.ButtonPushedFcn = createCallbackFcn(app, @FindUnprocessedDataButtonPushed, true);
            app.FindUnprocessedDataButton.Text = 'Find Unprocessed Data';
            % Show the figure after all components are created
            app.uiEEGshumin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainEEGshumin_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.uiEEGshumin)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.uiEEGshumin)
        end
    end
end