classdef PlayAudio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Panel_3                 matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        Panel_2                 matlab.ui.container.Panel
        TaskOrderLabel          matlab.ui.control.Label
        OKButton                matlab.ui.control.Button
        SubjectIDDropDown       matlab.ui.control.DropDown
        SubjectIDDropDownLabel  matlab.ui.control.Label
        TaskDropDown            matlab.ui.control.DropDown
        TaskDropDownLabel       matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        SlowButton              matlab.ui.control.StateButton
        Audiolength             matlab.ui.control.Label
        PlayPause               matlab.ui.control.StateButton
        StopPlay                matlab.ui.control.Button
        Back10s                 matlab.ui.control.Button
        AudioPlay               matlab.ui.control.Slider
    end

    
    properties (Access = private)
        PlayStatus % Description
        AudioDataY % Description
        RootFolder % Description
        AudioPlayer % Description
        Srate % Description
        JumpedSample % Description
        AudioDataY25 % Description
    end
    
    methods (Access = private)
        
        function [ta2as,ta5s] = AudioOrder(app)
            fid = fopen([app.RootFolder 'RawData/InfoTXT/' app.SubjectIDDropDown.Value '.txt'],'r');
            TaOr = textscan(fid,'%*s%*s%s',1);
            fclose(fid);
            TaskOrder = TaOr{1,1}{1,1};
            app.TaskOrderLabel.Text = TaskOrder;
            ta2as = 4*find(TaskOrder=='2')-1;
            ta5s = 4*find(TaskOrder=='5')-1+2*floor(sqrt(find(TaskOrder=='5')/find(TaskOrder=='2')));
        end
        
        function LoadAudioData(app)
            [ta2as,ta5s] = AudioOrder(app);
            
            CuttimeVideo = load([app.RootFolder 'ProcessedData/Event/' app.SubjectIDDropDown.Value '/' app.SubjectIDDropDown.Value 'CuttimeVideo.txt']);
            load([app.RootFolder 'ProcessedData/Video/' app.SubjectIDDropDown.Value '/' app.SubjectIDDropDown.Value '.mat'],'-mat','y','Fs');%
            app.Srate = Fs;
            
            TimeTa2a = round((CuttimeVideo(ta2as+[0 1])+[1; -1])*app.Srate);
            TimeTa2b = round((CuttimeVideo(ta2as+[2 3])+[1; -1])*app.Srate);
            TimeTa5  = round((CuttimeVideo(ta5s+[0 1])+[1; -1])*app.Srate);
            
            app.AudioDataY25.TA2A = y(TimeTa2a(1):TimeTa2a(2));
            app.AudioDataY25.TA2B = y(TimeTa2b(1):TimeTa2b(2));
            app.AudioDataY25.TA5 = y(TimeTa5(1):TimeTa5(2));
        end
        
        function SelectTask2Play(app)
            if app.TaskDropDown.Value == 1
                app.AudioDataY = app.AudioDataY25.TA2A;
            elseif app.TaskDropDown.Value == 2
                app.AudioDataY = app.AudioDataY25.TA2B;
            elseif app.TaskDropDown.Value == 3
                app.AudioDataY = app.AudioDataY25.TA5;
            end
            app.JumpedSample = 0;
            plot(app.UIAxes,(1:length(app.AudioDataY))/app.Srate,abs(app.AudioDataY))
            xlim(app.UIAxes,[0 round(length(app.AudioDataY)/app.Srate)]);
            app.AudioPlayer = audioplayer(app.AudioDataY,app.Srate*(1-0.1*app.SlowButton.Value));
            app.Audiolength.Text = num2str(round(length(app.AudioDataY)/app.Srate));
            app.UIAxes.XTick=(2:2:round(length(app.AudioDataY)/app.Srate));%/round(length(app.AudioDataY)/app.Srate);
%             app.UIAxes.XTickLabel=(5:5:round(length(app.AudioDataY)/app.Srate));%/round(length(app.AudioDataY)/app.Srate);

%             app.AudioPlay.MajorTicks = (5:5:round(length(app.AudioDataY)/app.Srate));%/round(length(app.AudioDataY)/app.Srate);
%             app.AudioPlay.MajorTickLabels = string(num2cell(5:5:round(length(app.AudioDataY)/app.Srate)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RootFolder = 'E:/OneDrive - Politecnico di Milano/201920/PhD/Shumin_Research/Protocol_shumin/Data/';
            fid = fopen([app.RootFolder 'RawData/FileCreationTime.txt']);
            IdList = textscan(fid,'%3s%8s%8s%8s');
            fclose(fid);
            app.SubjectIDDropDown.Items=IdList{1,1};
            app.SubjectIDDropDown.Value = app.SubjectIDDropDown.Items{1};
            app.TaskDropDown.ItemsData = {1,2,3};
            app.TaskDropDown.Value = app.TaskDropDown.ItemsData{1};
            app.PlayStatus = 0;
            LoadAudioData(app);
            SelectTask2Play(app);
        end

        % Value changed function: PlayPause
        function PlayPauseValueChanged(app, event)
            value = app.PlayPause.Value;
            if value == 1 && app.PlayStatus ==0
                play(app.AudioPlayer);
                app.PlayStatus = 1;
                while app.AudioPlayer.CurrentSample< app.AudioPlayer.TotalSamples+1 && strcmp(app.AudioPlayer.Running,'on')
                    app.AudioPlay.Value = (app.AudioPlayer.CurrentSample+app.JumpedSample)/length(app.AudioDataY);
                    pause(0.1);
                end
                if app.AudioPlayer.CurrentSample==1
                    app.PlayPause.Value = 0;app.AudioPlay.Value = 0;
                end
            elseif value ==1 && app.PlayStatus ==1
                resume(app.AudioPlayer);
                while app.AudioPlayer.CurrentSample<app.AudioPlayer.TotalSamples+1 && strcmp(app.AudioPlayer.Running,'on')
                    app.AudioPlay.Value = (app.AudioPlayer.CurrentSample+app.JumpedSample)/length(app.AudioDataY);
                    pause(0.1);
                end
                if app.AudioPlayer.CurrentSample==1
                    app.PlayPause.Value = 0;app.AudioPlay.Value = 0;
                end
            elseif value == 0
                pause(app.AudioPlayer);
                app.AudioPlay.Value = (app.AudioPlayer.CurrentSample+app.JumpedSample)/length(app.AudioDataY);
            end
        end

        % Button pushed function: StopPlay
        function StopPlayButtonPushed(app, event)
            stop(app.AudioPlayer);
            app.AudioPlay.Value = 0;
            app.JumpedSample = 0;
        end

        % Button pushed function: Back10s
        function Back10sButtonPushed(app, event)
            pause(app.AudioPlayer); app.JumpedSample = max(app.JumpedSample + app.AudioPlayer.CurrentSample-10*app.Srate,1); stop(app.AudioPlayer);
            app.AudioPlayer = audioplayer(app.AudioDataY(app.JumpedSample:end),app.Srate*(1-0.1*app.SlowButton.Value));
            app.AudioPlay.Value = app.JumpedSample/length(app.AudioDataY);
            play(app.AudioPlayer);
            while app.AudioPlayer.CurrentSample<app.AudioPlayer.TotalSamples+1 && strcmp(app.AudioPlayer.Running,'on')
                app.AudioPlay.Value = (app.AudioPlayer.CurrentSample+app.JumpedSample)/length(app.AudioDataY);
                pause(0.1);
            end
            if app.AudioPlayer.CurrentSample==1
                app.PlayPause.Value = 0;
                app.AudioPlayer = audioplayer(app.AudioDataY,app.Srate*(1-0.1*app.SlowButton.Value));
                app.JumpedSample = 0;
                app.AudioPlay.Value = 0;
            end
        end

        % Value changed function: AudioPlay
        function AudioPlayValueChanged(app, event)
            app.JumpedSample = max(round(app.AudioPlay.Value*length(app.AudioDataY)),1); stop(app.AudioPlayer);
            app.AudioPlayer = audioplayer(app.AudioDataY(app.JumpedSample:end),app.Srate*(1-0.1*app.SlowButton.Value));
            app.AudioPlay.Value = app.JumpedSample/length(app.AudioDataY);
            play(app.AudioPlayer); app.PlayPause.Value = 1;
            while app.AudioPlayer.CurrentSample<app.AudioPlayer.TotalSamples+1 && strcmp(app.AudioPlayer.Running,'on')
                app.AudioPlay.Value = (app.AudioPlayer.CurrentSample+app.JumpedSample)/length(app.AudioDataY);
                pause(0.1);
            end
            if app.AudioPlayer.CurrentSample==1
                app.PlayPause.Value = 0;
                app.AudioPlayer = audioplayer(app.AudioDataY,app.Srate*(1-0.1*app.SlowButton.Value));
                app.JumpedSample = 0;app.AudioPlay.Value = 0;
            end
        end

        % Value changing function: AudioPlay
        function AudioPlayValueChanging(app, event)
            pause(app.AudioPlayer);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            LoadAudioData(app);
            SelectTask2Play(app);
        end

        % Value changed function: TaskDropDown
        function TaskDropDownValueChanged(app, event)
            app.PlayPause.Value = 0;
            app.JumpedSample = 0;
            app.AudioPlay.Value = 0;
            SelectTask2Play(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.AudioPlay = uislider(app.Panel);
            app.AudioPlay.Limits = [0 1];
            app.AudioPlay.MajorTicks = [];
            app.AudioPlay.MajorTickLabels = {''};
            app.AudioPlay.ValueChangedFcn = createCallbackFcn(app, @AudioPlayValueChanged, true);
            app.AudioPlay.ValueChangingFcn = createCallbackFcn(app, @AudioPlayValueChanging, true);
            app.AudioPlay.MinorTicks = [];
            app.Back10s = uibutton(app.Panel, 'push');
            app.Back10s.ButtonPushedFcn = createCallbackFcn(app, @Back10sButtonPushed, true);
            app.Back10s.Text = '⏪';
            app.StopPlay = uibutton(app.Panel, 'push');
            app.StopPlay.ButtonPushedFcn = createCallbackFcn(app, @StopPlayButtonPushed, true);
            app.StopPlay.Text = '⏹';
            app.PlayPause = uibutton(app.Panel, 'state');
            app.PlayPause.ValueChangedFcn = createCallbackFcn(app, @PlayPauseValueChanged, true);
            app.PlayPause.Text = '⏯';
            app.Audiolength = uilabel(app.Panel);
            app.SlowButton = uibutton(app.Panel, 'state');
            app.SlowButton.Text = 'Speed *0.9';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TaskDropDownLabel = uilabel(app.Panel_2);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.Panel_2);
            app.TaskDropDown.Items = {'AU Known', 'AU New', 'Empty Glass'};
            app.TaskDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskDropDownValueChanged, true);
            app.TaskDropDown.Value = 'AU Known';
            app.SubjectIDDropDownLabel = uilabel(app.Panel_2);
            app.SubjectIDDropDownLabel.Text = 'Subject ID';
            app.SubjectIDDropDown = uidropdown(app.Panel_2);
            app.OKButton = uibutton(app.Panel_2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = {'OK'; ''};
            app.TaskOrderLabel = uilabel(app.Panel_2);
            app.TaskOrderLabel.Text = '12345';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel_3);
            xlabel(app.UIAxes, 'time (s)')
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlayAudio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end