classdef PlotChecking_BeforeAfterProcessing_shumin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        DatasettoplotDropDownLabel  matlab.ui.control.Label
        DatasettoplotDropDown       matlab.ui.control.DropDown
        PlotButton                  matlab.ui.control.Button
        MovementDropDownLabel       matlab.ui.control.Label
        MovementDropDown            matlab.ui.control.DropDown
        UIAxes_1                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs %sampling frequency
        rawdata1
        bpdata1
        bssccadata1
        rawdata2
        bpdata2
        bssccadata2
        rawdata3
        bpdata3
        bssccadata3
    end
    
    methods (Access = private)
        function updateplots(app)
            if app.DatasettoplotDropDown.Value < 4
                d2praw = app.(['rawdata' num2str(app.DatasettoplotDropDown.Value)])(app.MovementDropDown.Value).s;
                d2pbefore = app.(['bpdata' num2str(app.DatasettoplotDropDown.Value)])(app.MovementDropDown.Value).s;
                d2pafter = app.(['bssccadata' num2str(app.DatasettoplotDropDown.Value)])(app.MovementDropDown.Value).s;
                app.UIAxes_1.Title.String=['(Raw-4250) ' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(app.DatasettoplotDropDown.Value)];
                app.UIAxes_2.Title.String=['Before BSSCCA ' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(app.DatasettoplotDropDown.Value)];
                app.UIAxes_3.Title.String=['After BSSCCA ' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(app.DatasettoplotDropDown.Value)];
                d2praw  = d2praw  + cumsum(ones(size(d2praw))*100)-4250;
            else
                d2praw = app.bssccadata1(app.MovementDropDown.Value).s;
                d2pbefore =app.bssccadata2(app.MovementDropDown.Value).s;
                d2pafter = app.bssccadata3(app.MovementDropDown.Value).s;
                app.UIAxes_1.Title.String=['After BSSCCA' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(1)];
                app.UIAxes_2.Title.String=['After BSSCCA' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(2)];
                app.UIAxes_3.Title.String=['After BSSCCA' app.MovementDropDown.Items(app.MovementDropDown.Value) app.DatasettoplotDropDown.Items(3)];
                d2praw  = d2praw  + cumsum(ones(size(d2praw))*100);
            end
            
            timer = (1:size(d2praw,2)) / app.fs;
            timeb = (1:size(d2pbefore,2)) / app.fs;
            timea = (1:size(d2pafter,2)) / app.fs;
                        
            d2pbefore  = d2pbefore  + cumsum(ones(size(d2pbefore))*100);
            d2pafter = d2pafter + cumsum(ones(size(d2pafter))*100);
            
            plot(app.UIAxes_1,timer,d2praw);
            plot(app.UIAxes_2,timeb,d2pbefore);
            plot(app.UIAxes_3,timea,d2pafter);


            app.UIAxes_1.YTick = 100:100:1400;
            app.UIAxes_2.YTick = 100:100:1400;
            app.UIAxes_3.YTick = 100:100:1400;
            app.UIAxes_1.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            app.UIAxes_2.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            app.UIAxes_3.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            app.UIAxes_1.YLim = [0 1800];
            
            app.UIAxes_2.XLim = app.UIAxes_1.XLim;
            app.UIAxes_3.XLim = app.UIAxes_1.XLim;
            app.UIAxes_2.YLim = app.UIAxes_1.YLim;
            app.UIAxes_3.YLim = app.UIAxes_1.YLim;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs=128;
            
            app.MovementDropDown.Items =  {'Eyes Open', 'Eyes Closed','Open without blink','Continues Blink','Horizontal Eye movement','Vertical Eye movement',...
                'Head Yes','Head No','Head What','Write in silence','Write + Read','Communication','Read','Sketch','Walk','Game-Shape','Game-Number','Game-Color'};
            app.MovementDropDown.ItemsData = 1:18;
            app.MovementDropDown.Value = 1;
            
            app.DatasettoplotDropDown.Items = {'BSSCCA WinL - 1s, WinS - 0.3s','BSSCCA WinL - 2.5s, WinS - 1.2s','BSSCCA WinL - 3s, WinS - 1.4s','Comparison between BSSCCA with diff. para.'};
            app.DatasettoplotDropDown.ItemsData = 1:4;
            app.DatasettoplotDropDown.Value = 1;
            
            data1=load('Shumin_Win1s0d3s.mat');
            data2=load('ShuminMoveTest.mat');
            data3=load('Shumin_Win3s1d4s.mat');
            
            app.rawdata1=data1.ShuminMoveTest.raweegdata;
            app.bpdata1=data1.ShuminMoveTest.bandpassdata;
            app.bssccadata1=data1.ShuminMoveTest.bssccadata;
            app.rawdata2=data2.ShuminMoveTest.raweegdata;
            app.bpdata2=data2.ShuminMoveTest.bandpassdata;
            app.bssccadata2=data2.ShuminMoveTest.bssccadata;
            app.rawdata3=data3.ShuminMoveTest.raweegdata;
            app.bpdata3=data3.ShuminMoveTest.bandpassdata;
            app.bssccadata3=data3.ShuminMoveTest.bssccadata;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updateplots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Before Processing')
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'μV')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'After Processing')
            xlabel(app.UIAxes_3, 'time (s)')
            ylabel(app.UIAxes_3, 'μV')
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Raw Data')
            xlabel(app.UIAxes_1, 'time (s)')
            ylabel(app.UIAxes_1, 'μV')
            app.Panel = uipanel(app.UIFigure);
            app.MovementDropDown = uidropdown(app.Panel);
            app.MovementDropDownLabel = uilabel(app.Panel);
            app.MovementDropDownLabel.Text = 'Movement';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.DatasettoplotDropDown = uidropdown(app.Panel);
            app.DatasettoplotDropDownLabel = uilabel(app.Panel);
            app.DatasettoplotDropDownLabel.Text = 'Dataset to plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotChecking_BeforeAfterProcessing_shumin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end