classdef PlotChecking_SignalRemain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        ReliableRangeLabel     matlab.ui.control.Label
        BandDropDownLabel      matlab.ui.control.Label
        BandDropDown           matlab.ui.control.DropDown
        TaskGameDropDownLabel  matlab.ui.control.Label
        TaskGameDropDown       matlab.ui.control.DropDown
        PlotButton             matlab.ui.control.Button
        SubjectDropDownLabel   matlab.ui.control.Label
        SubjectDropDown        matlab.ui.control.DropDown
        UIAxes_1               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs %sampling frequency
        ProcessedData
        normalEEGthreshold
        reliablerange
    end
    
    methods (Access = private)
        function updateplots(app)
            subjecti=app.SubjectDropDown.Value;
            taski=app.TaskGameDropDown.Value;
            bandi=app.BandDropDown.Value;
            
            if taski > 1
            d2p1=app.ProcessedData(subjecti).Task{taski, 1}.SubbandSignal(bandi).s;
            d2p2=app.ProcessedData(subjecti).Task{taski, 1}.SubbandSignal(bandi).s_br;
            d2p3=app.ProcessedData(subjecti).Task{taski, 1}.dcrmEEG;
            else
            d2p1=app.normalEEGthreshold.eyesopen{subjecti, 1}.SubbandSignal(bandi).s;
            d2p2=app.normalEEGthreshold.eyesopen{subjecti, 1}.SubbandSignal(bandi).s_br;
            d2p3=app.normalEEGthreshold.eyesopen{subjecti, 1}.dcrmEEG;
            end

            d2p1  = d2p1  + cumsum(ones(size(d2p1))*100);
            d2p2  = d2p2  + cumsum(ones(size(d2p2))*100);
            d2p3 = d2p3 + cumsum(ones(size(d2p3))*100);

            time1 = (1:size(d2p1,2)) / app.fs;
            time2 = (1:size(d2p2,2)) / app.fs;
            time3 = (1:size(d2p3,2)) / app.fs;
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            plot(app.UIAxes_1,time1,d2p1);
            plot(app.UIAxes_2,time2,d2p2);
            plot(app.UIAxes_3,time3,d2p3);
                        
            app.UIAxes_1.YTick = 100:100:1400;  app.UIAxes_2.YTick = 100:100:1400;  app.UIAxes_3.YTick = 100:100:1400;
            app.UIAxes_1.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            app.UIAxes_2.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};
            app.UIAxes_3.YTickLabel = {'AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6','F4','F8','AF4'};

%             app.UIAxes_3.XLim=xlimtis(taski);
            app.UIAxes_1.YLim = [0 1800];
            app.UIAxes_1.XLim = app.UIAxes_3.XLim;   app.UIAxes_2.XLim = app.UIAxes_3.XLim;
            app.UIAxes_2.YLim = app.UIAxes_1.YLim;   app.UIAxes_3.YLim = app.UIAxes_1.YLim;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs=128;
            subjectIDlist=[1:7 9:11 13:18 20:27 29:37];
            for ii=1:33
                app.SubjectDropDown.Items{1,ii}=['00' num2str(subjectIDlist(ii))];
                app.SubjectDropDown.Items{1,ii}=app.SubjectDropDown.Items{1,ii}(end-2:end);
            end

            app.SubjectDropDown.ItemsData = 1:33;
            app.SubjectDropDown.Value = 1;
            
            app.TaskGameDropDown.Items = {'Eyes Open', 'Eyes Closed', 'Game 1', 'Game 2', 'Game 3', 'Game 4', 'Game 5','2-back','AU-known','AU-new','TTCT','DwMT','PS',...
                'DwMT - Reading','DwMT - Selecting','DwMT - Sketching'};
            app.TaskGameDropDown.ItemsData = 1:16;
            app.TaskGameDropDown.Value = 1;
            
            app.BandDropDown.Items =  {'All','Theta','Alpha','Beta','Gamma'};
            app.BandDropDown.ItemsData = 1:5;
            app.BandDropDown.Value = 1;

            data1=load('ProcessedDataGroup2_reliablerange16.mat');
            
            app.ProcessedData=data1.ProcessedData;
            app.normalEEGthreshold=data1.normalEEGthreshold;
            app.reliablerange=data1.reliablerange;

            app.ReliableRangeLabel.Text = [app.ReliableRangeLabel.Text num2str(app.reliablerange)];
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            updateplots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Data alter filtering, audio control, no threshold control')
            xlabel(app.UIAxes_2, 'time (s)')
            ylabel(app.UIAxes_2, 'μV')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Data after dc-offset removal, no bandpass filtering applied')
            xlabel(app.UIAxes_3, 'time (s)')
            ylabel(app.UIAxes_3, 'μV')
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Final Clearned Data')
            xlabel(app.UIAxes_1, 'time (s)')
            ylabel(app.UIAxes_1, 'μV')
            app.Panel = uipanel(app.UIFigure);
            app.SubjectDropDown = uidropdown(app.Panel);
            app.SubjectDropDownLabel = uilabel(app.Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.PlotButton = uibutton(app.Panel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TaskGameDropDown = uidropdown(app.Panel);
            app.TaskGameDropDownLabel = uilabel(app.Panel);
            app.TaskGameDropDownLabel.Text = 'Task/Game';
            app.BandDropDown = uidropdown(app.Panel);
            app.BandDropDownLabel = uilabel(app.Panel);
            app.BandDropDownLabel.Text = 'Band';
            app.ReliableRangeLabel = uilabel(app.Panel);
            app.ReliableRangeLabel.Text = 'Reliable Range : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotChecking_SignalRemain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end