classdef RadarPlotshumin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RadarPlot                  matlab.ui.Figure
        TaskGamePanel              matlab.ui.container.Panel
        TaskGame                   matlab.ui.control.Switch
        RefCheckBox                matlab.ui.control.CheckBox
        EmptytheglassButton        matlab.ui.control.StateButton
        DesignwithMorphologicalTableButton  matlab.ui.control.StateButton
        SketchButton               matlab.ui.control.StateButton
        AUnewButton                matlab.ui.control.StateButton
        AUknownButton              matlab.ui.control.StateButton
        NbackButton                matlab.ui.control.StateButton
        PlotSettingPanel           matlab.ui.container.Panel
        KeepCurrentFigureCheckBox  matlab.ui.control.CheckBox
        RaxesLimitLabel            matlab.ui.control.Label
        RUlimit                    matlab.ui.control.NumericEditField
        RLlimit                    matlab.ui.control.NumericEditField
        RemoveSpeakSwitch          matlab.ui.control.Switch
        RemoveSpeakSwitchLabel     matlab.ui.control.Label
        ScaleSwitch                matlab.ui.control.Switch
        ScaleSwitchLabel           matlab.ui.control.Label
        FrequencyBandButtonGroup   matlab.ui.container.ButtonGroup
        Gamma2BandButton           matlab.ui.control.ToggleButton
        Gamma1BandButton           matlab.ui.control.ToggleButton
        GammaBandButton            matlab.ui.control.ToggleButton
        Beta3BandButton            matlab.ui.control.ToggleButton
        Beta2BandButton            matlab.ui.control.ToggleButton
        BetaBandButton             matlab.ui.control.ToggleButton
        Beta1BandButton            matlab.ui.control.ToggleButton
        Alpha2BandButton           matlab.ui.control.ToggleButton
        Alpha1BandButton           matlab.ui.control.ToggleButton
        AlphaBandButton            matlab.ui.control.ToggleButton
        ThetaBandButton            matlab.ui.control.ToggleButton
        AllBandButton              matlab.ui.control.ToggleButton
        ParticipantInfoPanel       matlab.ui.container.Panel
        SubjectIDDropDown          matlab.ui.control.DropDown
        SubjectIDDropDownLabel     matlab.ui.control.Label
        TaskOrder                  matlab.ui.control.Label
        TaskOrderLabel             matlab.ui.control.Label
        BackgroundDropDown         matlab.ui.control.DropDown
        BackgroundDropDownLabel    matlab.ui.control.Label
        DominantHandSwitch         matlab.ui.control.Switch
        DominantHandSwitchLabel    matlab.ui.control.Label
        AgeEditField               matlab.ui.control.EditField
        AgeEditFieldLabel          matlab.ui.control.Label
        GenderSwitch               matlab.ui.control.Switch
        GenderSwitchLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingPlotRadarApp % Description
        PathLoad % Description
        %         Pax % Description
        RadarData % Description
    end
    
    methods (Access = private)
        
        function updateRadarPlots(app)
            ColorPallet = [0    0.4470    0.7410;
                0.8500    0.3250    0.0980;
                0.9290    0.6940    0.1250;
                0.4940    0.1840    0.5560;
                0.4660    0.6740    0.1880;
                0.3010    0.7450    0.9330;
                0.6350    0.0780    0.1840];
            legendlist = {'2-Back','AU Know','AU New','Sketch','Design with MT','Empty Glass'};
            PlotTaskN = [app.NbackButton.Value, app.AUknownButton.Value, app.AUnewButton.Value, app.SketchButton.Value, ...
                app.DesignwithMorphologicalTableButton.Value, app.EmptytheglassButton.Value];
            PlotTaskLegend=legendlist (PlotTaskN);
            PlotTaskColor = ColorPallet(PlotTaskN,:);
            
            MeanDataPlot = SelectDataForPlot(app);
            theta = deg2rad(107.5:25:432.5);
            if app.KeepCurrentFigureCheckBox.Value ==0
                figHandles = findobj('type', 'figure', '-not', 'name', {'Radar Plot','uiEEGshumin'});
                close(figHandles)
            end
            figure
            colororder(PlotTaskColor)
            polarplot(theta,MeanDataPlot,'-*','LineWidth',2.5);%app.Pax,
            ax=gca;
            ax.RLim=[app.RLlimit.Value app.RUlimit.Value];
            ax.LineWidth = 0.5;
            ax.ThetaTick =([22.5 47.5 72.5 107.5:25:360 ]);
            ax.ThetaTickLabel=({'F4','F8','AF4','AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6'});
            
            %%%%%%%%%%%%%%%legend%%%%%%%%%%%%%%%%%%%%%!!!!
            
            legend(ax,PlotTaskLegend);
            %app.Pax.RLim = [app.RLlimit.Value app.RUlimit.Value];
            %app.Pax.ThetaTick =([22.5 47.5 72.5 107.5:25:360 ]);
            %app.Pax.ThetaTickLabel=({'F4','F8','AF4','AF3','F7','F3','FC5','T7','P7','O1','O2','P8','T8','FC6'});
            %legend(app.Pax,{'2-Back','AU Know','AU New','Sketch','Design with MT','Empty Glass'},'Position',[0.76 0.1 0.2 0]);
            
        end
        
        function RadarData = SwitchSubject(app)
            MapDataPath=[app.PathLoad 'ProcessedData/EEG/audiofree/' app.SubjectIDDropDown.Value '_log'];
            if exist([MapDataPath '_withS.mat'],'file')==2 && exist([MapDataPath '.mat'],'file')==2
                RadarData.withS=load([MapDataPath '_withS.mat']);
                RadarData.removeS=load([MapDataPath '.mat']);
            else
                warndlg('There no file plot radar map, please run signal processing first','No such file');
                return
            end
        end
        
        function FreqB = SwitchFreqBand(app)
            ValueFreqBs = [app.AllBandButton.Value, app.ThetaBandButton.Value, app.AlphaBandButton.Value, app.Alpha1BandButton.Value,...
                app.Alpha2BandButton.Value, app.BetaBandButton.Value,  app.Beta1BandButton.Value,  app.Beta2BandButton.Value,...
                app.Beta3BandButton.Value,  app.GammaBandButton.Value, app.Gamma1BandButton.Value, app.Gamma2BandButton.Value];
            FreqNames ={'AllBand','ThetaBand','AlphaBand','Alpha1Band','Alpha2Band','BetaBand','Beta1Band','Beta2Band','Beta3Band','GammaBand','Gamma1Band','Gamma2Band'};
            FreqB = FreqNames{1,ValueFreqBs==1};
        end
        
        function MeanDataPlot = SelectDataForPlot(app)
            taskorder = char(app.TaskOrder.Text);
            Taidx = [1 find(taskorder == '2') find(taskorder == '3') find(taskorder == '4') find(taskorder == '5')];
            FileOrder=ones(11,1);
            FileOrder([1 3 6 8 10])=Taidx*2-1;
            FileOrder([2 4 7 9 11])=Taidx*2;FileOrder(5)=Taidx(2)*2+1;
            for ii = 3:5
                if Taidx(ii)>Taidx(2)
                    FileOrder([ii*2 ii*2+1])=FileOrder([ii*2 ii*2+1])+1;
                end
            end
            RemovSound = app.RemoveSpeakSwitch.Value;
            switch RemovSound
                case 'Remove'
                    DataPlot = app.RadarData.removeS;
                case 'Keep'
                    DataPlot = app.RadarData.withS;
            end
            SwitchTaskGame = app.TaskGame.Value;
            
            switch SwitchTaskGame
                case 'Task'
                    DataTaG = [2*app.NbackButton.Value 4*app.AUknownButton.Value 5*app.AUnewButton.Value 7*app.SketchButton.Value 9*app.DesignwithMorphologicalTableButton.Value 11*app.EmptytheglassButton.Value];
                case 'Game'
                    DataTaG = [1*app.NbackButton.Value 3*app.AUknownButton.Value 3*app.AUnewButton.Value 6*app.SketchButton.Value 8*app.DesignwithMorphologicalTableButton.Value 10*app.EmptytheglassButton.Value];
            end
            DataTaG(DataTaG==0)=[];
            
            FreqBand = SwitchFreqBand(app);
            LogScale = app.ScaleSwitch.Value;
            if strcmp(LogScale,'Linear')
                MeanDataAll = DataPlot.eegAFhz.(FreqBand);
                if app.RefCheckBox.Value ==1
                    Ref=min(MeanDataAll(:,FileOrder([1 3 6 8 10])),[],2); %14*1
                else
                    Ref=ones(14,1); %14*1
                end
                MeanDataPlot = MeanDataAll(:,FileOrder(DataTaG))./Ref;
            elseif strcmp(LogScale,'Log')
                MeanDataAll = DataPlot.eegAFhzlog.(FreqBand);
                if app.RefCheckBox.Value ==1
                    Ref=min(MeanDataAll(:,FileOrder([1 3 6 8 10])),[],2);
                else
                    Ref = zeros(14,1);
                end
                MeanDataPlot = MeanDataAll(:,FileOrder(DataTaG))-Ref;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, passInfo)
            app.CallingPlotRadarApp = mainapp;
            app.PathLoad = passInfo{1,7};
            fid = fopen([app.PathLoad 'RawData/FileCreationTime.txt']);
            IdList = textscan(fid,'%3s%8s%8s%8s');
            fclose(fid);
            app.SubjectIDDropDown.Items=IdList{1,1};
            app.SubjectIDDropDown.Value = passInfo{1,1};
            app.AgeEditField.Value = passInfo{1,2};
            app.GenderSwitch.Value = passInfo{1,3};
            app.DominantHandSwitch.Value = passInfo{1,4};
            app.BackgroundDropDown.Value = passInfo{1,5};
            app.TaskOrder.Text = passInfo{1,6};
            % app.Pax = polaraxes(app.RadarPlot);
            % app.Pax.Units = 'pixels';
            % app.Pax.Position = [340 150 445 445];
            app.RadarData = SwitchSubject(app);
            updateRadarPlots(app);
        end

        % Selection changed function: FrequencyBandButtonGroup
        function FrequencyBandButtonGroupSelectionChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: ScaleSwitch
        function ScaleSwitchValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: RemoveSpeakSwitch
        function RemoveSpeakSwitchValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: RLlimit
        function RLlimitValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: RUlimit
        function RUlimitValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: SubjectIDDropDown
        function SubjectIDDropDownValueChanged(app, event)
            RawInfoFile = [app.PathLoad 'RawData/InfoTXT/' app.SubjectIDDropDown.Value '.txt'];
            if exist(RawInfoFile,'file')==2
                fid = fopen(RawInfoFile);
                knownInfo = textscan(fid,'%s%s%s');
                fclose(fid);
                for Infoi=1:length(knownInfo{1,1})
                    if strcmp(knownInfo{1,1}(Infoi),'taskorder')
                        app.TaskOrder.Text=knownInfo{1,3}(Infoi);
                    end
                    if strcmp(knownInfo{1,1}(Infoi),'Gender')
                        app.GenderSwitch.Value=knownInfo{1,3}(Infoi);
                    end
                    if strcmp(knownInfo{1,1}(Infoi),'DominantHand')
                        app.DominantHandSwitch.Value=knownInfo{1,3}(Infoi);
                    end
                    if strcmp(knownInfo{1,1}(Infoi),'Age')
                        app.AgeEditField.Value=char(knownInfo{1,3}(Infoi));
                    end
                end
                winopen(RawInfoFile);
                app.RadarData = SwitchSubject(app);
                updateRadarPlots(app);
            else
                warndlg('No such subject, please recheck the list or process the signal of this subject first.','No data');
                app.SubjectIDDropDown.Value = '999';
                app.TaskOrder.Text = '*****';
            end
        end

        % Value changed function: RefCheckBox
        function RefCheckBoxValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: NbackButton
        function NbackButtonValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: AUknownButton
        function AUknownButtonValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: AUnewButton
        function AUnewButtonValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: SketchButton
        function SketchButtonValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: DesignwithMorphologicalTableButton
        function DesignwithMorphologicalTableButtonValueChanged(app, event)
            updateRadarPlots(app);
        end

        % Value changed function: EmptytheglassButton
        function EmptytheglassButtonValueChanged(app, event)
            updateRadarPlots(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RadarPlot = uifigure('Visible', 'off');
            app.RadarPlot.Name = 'Radar Plot';
            app.ParticipantInfoPanel = uipanel(app.RadarPlot);
            app.ParticipantInfoPanel.Title = '  Participant Info';
            app.GenderSwitchLabel = uilabel(app.ParticipantInfoPanel);
            app.GenderSwitchLabel.Text = 'Gender:';
            app.GenderSwitch = uiswitch(app.ParticipantInfoPanel, 'slider');
            app.GenderSwitch.Items = {'M', 'F'};
            app.GenderSwitch.Value = 'M';
            app.AgeEditFieldLabel = uilabel(app.ParticipantInfoPanel);
            app.AgeEditFieldLabel.Text = 'Age:';
            app.AgeEditField = uieditfield(app.ParticipantInfoPanel, 'text');
            app.AgeEditField.Value = '100';
            app.DominantHandSwitchLabel = uilabel(app.ParticipantInfoPanel);
            app.DominantHandSwitchLabel.Text = 'Dominant Hand:';
            app.DominantHandSwitch = uiswitch(app.ParticipantInfoPanel, 'slider');
            app.DominantHandSwitch.Items = {'L', 'R'};
            app.DominantHandSwitch.Value = 'L';
            app.BackgroundDropDownLabel = uilabel(app.ParticipantInfoPanel);
            app.BackgroundDropDownLabel.Text = 'Background:';
            app.BackgroundDropDown = uidropdown(app.ParticipantInfoPanel);
            app.BackgroundDropDown.Items = {'Engineering', 'Design', 'Other'};
            app.BackgroundDropDown.Value = 'Engineering';
            app.TaskOrderLabel = uilabel(app.ParticipantInfoPanel);
            app.TaskOrderLabel.Text = 'Task Order:';
            app.TaskOrder = uilabel(app.ParticipantInfoPanel);
            app.TaskOrder.Text = '*****';
            app.SubjectIDDropDownLabel = uilabel(app.ParticipantInfoPanel);
            app.SubjectIDDropDownLabel.Text = 'Subject ID:';
            app.SubjectIDDropDown = uidropdown(app.ParticipantInfoPanel);
            app.SubjectIDDropDown.Items = {'000'};
            app.SubjectIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectIDDropDownValueChanged, true);
            app.SubjectIDDropDown.Value = '000';
            app.FrequencyBandButtonGroup = uibuttongroup(app.RadarPlot);
            app.FrequencyBandButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrequencyBandButtonGroupSelectionChanged, true);
            app.FrequencyBandButtonGroup.Title = '  Frequency Band';
            app.AllBandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.AllBandButton.Text = 'All Band  [4 45] Hz';
            app.AllBandButton.Value = true;
            app.ThetaBandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.ThetaBandButton.Text = 'Theta Band  [4 7] Hz';
            app.AlphaBandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.AlphaBandButton.Text = 'Alpha Band [7 13] Hz';
            app.Alpha1BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Alpha1BandButton.Text = '[8 10] Hz';
            app.Alpha2BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Alpha2BandButton.Text = '[10 13] Hz';
            app.Beta1BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Beta1BandButton.Text = '[13 16] Hz';
            app.BetaBandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.BetaBandButton.Text = 'Beta Band [13 30] Hz';
            app.Beta2BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Beta2BandButton.Text = '[16 22] Hz';
            app.Beta3BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Beta3BandButton.Text = '[22 30] Hz';
            app.GammaBandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.GammaBandButton.Text = 'Gamma Band [30 45] Hz';
            app.Gamma1BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Gamma1BandButton.Text = '[30 36] Hz';
            app.Gamma2BandButton = uitogglebutton(app.FrequencyBandButtonGroup);
            app.Gamma2BandButton.Text = '[36 45] Hz';
            app.PlotSettingPanel = uipanel(app.RadarPlot);
            app.PlotSettingPanel.Title = '  Plot Setting';
            app.ScaleSwitchLabel = uilabel(app.PlotSettingPanel);
            app.ScaleSwitchLabel.Text = 'Scale';
            app.ScaleSwitch = uiswitch(app.PlotSettingPanel, 'slider');
            app.ScaleSwitch.Items = {'Linear', 'Log'};
            app.ScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @ScaleSwitchValueChanged, true);
            app.ScaleSwitch.Value = 'Linear';
            app.RemoveSpeakSwitchLabel = uilabel(app.PlotSettingPanel);
            app.RemoveSpeakSwitchLabel.Text = 'Speaking interval';
            app.RemoveSpeakSwitch = uiswitch(app.PlotSettingPanel, 'slider');
            app.RemoveSpeakSwitch.Items = {'Remove', 'Keep'};
            app.RemoveSpeakSwitch.ValueChangedFcn = createCallbackFcn(app, @RemoveSpeakSwitchValueChanged, true);
            app.RemoveSpeakSwitch.Value = 'Remove';
            app.RLlimit = uieditfield(app.PlotSettingPanel, 'numeric');
            app.RLlimit.ValueChangedFcn = createCallbackFcn(app, @RLlimitValueChanged, true);
            app.RLlimit.Value = -2;
            app.RUlimit = uieditfield(app.PlotSettingPanel, 'numeric');
            app.RUlimit.ValueChangedFcn = createCallbackFcn(app, @RUlimitValueChanged, true);
            app.RUlimit.Value = 4;
            app.RaxesLimitLabel = uilabel(app.PlotSettingPanel);
            app.RaxesLimitLabel.Text = {'R-axes Limit'; '         --'};
            app.KeepCurrentFigureCheckBox = uicheckbox(app.PlotSettingPanel);
            app.KeepCurrentFigureCheckBox.Text = 'Keep Current Figure';
            app.KeepCurrentFigureCheckBox.Value = true;
            app.TaskGamePanel = uipanel(app.RadarPlot);
            app.TaskGamePanel.Title = 'Task/Game';
            app.NbackButton = uibutton(app.TaskGamePanel, 'state');
            app.NbackButton.ValueChangedFcn = createCallbackFcn(app, @NbackButtonValueChanged, true);
            app.NbackButton.Text = '2 - back';
            app.NbackButton.Value = true;
            app.AUknownButton = uibutton(app.TaskGamePanel, 'state');
            app.AUknownButton.ValueChangedFcn = createCallbackFcn(app, @AUknownButtonValueChanged, true);
            app.AUknownButton.Text = 'AU known';
            app.AUknownButton.Value = true;
            app.AUnewButton = uibutton(app.TaskGamePanel, 'state');
            app.AUnewButton.ValueChangedFcn = createCallbackFcn(app, @AUnewButtonValueChanged, true);
            app.AUnewButton.Text = 'AU new';
            app.AUnewButton.Value = true;
            app.SketchButton = uibutton(app.TaskGamePanel, 'state');
            app.SketchButton.ValueChangedFcn = createCallbackFcn(app, @SketchButtonValueChanged, true);
            app.SketchButton.Text = 'Sketch';
            app.SketchButton.Value = true;
            app.DesignwithMorphologicalTableButton = uibutton(app.TaskGamePanel, 'state');
            app.DesignwithMorphologicalTableButton.ValueChangedFcn = createCallbackFcn(app, @DesignwithMorphologicalTableButtonValueChanged, true);
            app.DesignwithMorphologicalTableButton.Text = 'Design with Morphological Table';
            app.DesignwithMorphologicalTableButton.Value = true;
            app.EmptytheglassButton = uibutton(app.TaskGamePanel, 'state');
            app.EmptytheglassButton.ValueChangedFcn = createCallbackFcn(app, @EmptytheglassButtonValueChanged, true);
            app.EmptytheglassButton.Text = 'Empty the glass';
            app.EmptytheglassButton.Value = true;
            app.RefCheckBox = uicheckbox(app.TaskGamePanel);
            app.RefCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefCheckBoxValueChanged, true);
            app.RefCheckBox.Text = 'Game Reference';
            app.RefCheckBox.Value = true;
            app.TaskGame = uiswitch(app.TaskGamePanel, 'slider');
            app.TaskGame.Items = {'Task', 'Game'};
            app.TaskGame.Value = 'Task';
            % Show the figure after all components are created
            app.RadarPlot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadarPlotshumin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RadarPlot)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RadarPlot)
        end
    end
end