classdef bssccacompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        StageDropDownLabel            matlab.ui.control.Label
        StageDropDown                 matlab.ui.control.DropDown
        SubjectCodeDropDown           matlab.ui.control.DropDown
        SubjectCodeDropDownLabel      matlab.ui.control.Label
        BackgroundDropDownLabel       matlab.ui.control.Label
        BackgroundDropDown            matlab.ui.control.DropDown
        ButtonGroup                   matlab.ui.container.ButtonGroup
        sbeforereadingButton          matlab.ui.control.RadioButton
        sbeforereadingButton_2        matlab.ui.control.RadioButton
        dynamicaveragingwindowPanel   matlab.ui.container.Panel
        dynamicaveragewindowSlider    matlab.ui.control.Slider
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        xAxisTimeRangePanel           matlab.ui.container.Panel
        dynamicaveragewindowSlider_2  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Data2Plot % Description
    end
    
    methods (Access = private)
        
%         function results = func(app)
%             
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DataTasks=load('DataTask4All.mat');
            
            app.SubjectCodeDropDown.Items = strsplit(num2str([5 13 19 21 22 29 30 32 33 41 47 54 62 69 70 71 72 79]));
            app.SubjectCodeDropDown.ItemsData = 1:18;
            app.SubjectCodeDropDown.Value = 1;
            
            app.Data2Plot=DataTasks.DataTask4.([app.BackgroundDropDown.Value num2str(app.SubjectCodeDropDown.Value)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.StageDropDownLabel = uilabel(app.Panel);
            app.StageDropDownLabel.Text = 'Stage';
            app.StageDropDown = uidropdown(app.Panel);
            app.StageDropDown.Items = {'Reading', 'Thinking', 'Writing', 'Sketching'};
            app.StageDropDown.Value = 'Reading';
            app.SubjectCodeDropDown = uidropdown(app.Panel);
            app.SubjectCodeDropDownLabel = uilabel(app.Panel);
            app.SubjectCodeDropDownLabel.Text = 'Subject Code';
            app.BackgroundDropDownLabel = uilabel(app.Panel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.Panel);
            app.BackgroundDropDown.Items = {'ME', 'ID', 'ARC'};
            app.BackgroundDropDown.Value = 'ME';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.sbeforereadingButton = uiradiobutton(app.ButtonGroup);
            app.sbeforereadingButton.Text = '+10s before reading';
            app.sbeforereadingButton.Value = true;
            app.sbeforereadingButton_2 = uiradiobutton(app.ButtonGroup);
            app.sbeforereadingButton_2.Text = '+  0s before reading';
            app.dynamicaveragingwindowPanel = uipanel(app.UIFigure);
            app.dynamicaveragingwindowPanel.Title = 'dynamic averaging window';
            app.dynamicaveragewindowSlider = uislider(app.dynamicaveragingwindowPanel);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Before BSSCCA')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'After BSSCCA')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.xAxisTimeRangePanel = uipanel(app.UIFigure);
            app.xAxisTimeRangePanel.Title = 'x-Axis Time Range';
            app.dynamicaveragewindowSlider_2 = uislider(app.xAxisTimeRangePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bssccacompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end